classdef vrControlGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        ConditionalTrialTypesTab        matlab.ui.container.Tab
        condMvmtGainSetting             matlab.ui.control.NumericEditField
        rewProbNewFreqLabel_3           matlab.ui.control.Label
        condMvmtGain                    matlab.ui.control.StateButton
        condProbRew                     matlab.ui.control.StateButton
        condActiveLick                  matlab.ui.control.StateButton
        removeButton                    matlab.ui.control.Button
        addnewButton                    matlab.ui.control.Button
        condActiveLickSetting           matlab.ui.control.NumericEditField
        rewProbNewFreqLabel_4           matlab.ui.control.Label
        condFreqSetting                 matlab.ui.control.NumericEditField
        rewProbNewFreqLabel_2           matlab.ui.control.Label
        condProbRewSetting              matlab.ui.control.NumericEditField
        rewProbNewProbLabel_2           matlab.ui.control.Label
        condTrialList                   matlab.ui.control.ListBox
        IndependentPRewardMovementGainTab  matlab.ui.container.Tab
        rewProbNewFreq                  matlab.ui.control.NumericEditField
        rewProbNewFreqLabel             matlab.ui.control.Label
        rewProbNewProb                  matlab.ui.control.NumericEditField
        rewProbNewProbLabel             matlab.ui.control.Label
        rewProbRemove                   matlab.ui.control.Button
        rewProbAddNew                   matlab.ui.control.Button
        rewProbList                     matlab.ui.control.ListBox
        customProbReward                matlab.ui.control.CheckBox
        customProbRewardGenerator       matlab.ui.control.EditField
        randRewardWindow                matlab.ui.control.DropDown
        randReward                      matlab.ui.control.CheckBox
        movementGain                    matlab.ui.control.NumericEditField
        movementGainLabel               matlab.ui.control.Label
        maxGain                         matlab.ui.control.NumericEditField
        maxGainLabel                    matlab.ui.control.Label
        minGain                         matlab.ui.control.NumericEditField
        minGainLabel                    matlab.ui.control.Label
        customGainGenerator             matlab.ui.control.EditField
        customGain                      matlab.ui.control.CheckBox
        randGainWindow                  matlab.ui.control.DropDown
        randGain                        matlab.ui.control.CheckBox
        animalSettingsID                matlab.ui.control.NumericEditField
        animalSettingsIDLabel           matlab.ui.control.Label
        animalSettingsButton            matlab.ui.control.Button
        animalSettingsPath              matlab.ui.control.EditField
        runExperiment                   matlab.ui.control.Button
        previewExperiment               matlab.ui.control.Button
        saveSettings                    matlab.ui.control.Button
        loadSettings                    matlab.ui.control.Button
        printrigparametersButton        matlab.ui.control.Button
        IntertrialTimingsPanel          matlab.ui.container.Panel
        distributionITI                 matlab.ui.control.DropDown
        randomMeanITI                   matlab.ui.control.NumericEditField
        randomMeanITILabel              matlab.ui.control.Label
        randomizeITI                    matlab.ui.control.CheckBox
        minimumITI                      matlab.ui.control.NumericEditField
        minimumITILabel                 matlab.ui.control.Label
        maxTrialDuration                matlab.ui.control.NumericEditField
        maxTrialDurationLabel           matlab.ui.control.Label
        printenvironmentinfoButton      matlab.ui.control.Button
        vrDirectory                     matlab.ui.control.EditField
        vrDirectoryLabel                matlab.ui.control.Label
        vrenvOptions                    matlab.ui.control.ListBox
        vrenvOptionsLabel               matlab.ui.control.Label
        vrenvInUse                      matlab.ui.control.ListBox
        vrenvInUseLabel                 matlab.ui.control.Label
        envSettings                     matlab.ui.control.DropDown
        envSettingsLabel                matlab.ui.control.Label
        envLength                       matlab.ui.control.NumericEditField
        envLengthLabel                  matlab.ui.control.Label
        dsFactor                        matlab.ui.control.NumericEditField
        dsFactorLabel                   matlab.ui.control.Label
        rewardPosition                  matlab.ui.control.NumericEditField
        rewardPositionLabel             matlab.ui.control.Label
        rewardTolerance                 matlab.ui.control.NumericEditField
        rewardToleranceLabel            matlab.ui.control.Label
        envOrder                        matlab.ui.control.DropDown
        envOrderLabel                   matlab.ui.control.Label
        envActive                       matlab.ui.control.StateButton
        messagestouserTextArea          matlab.ui.control.TextArea
        messagestouserTextAreaLabel     matlab.ui.control.Label
        vrExtension                     matlab.ui.control.EditField
        fileextensionforenvironmentsLabel  matlab.ui.control.Label
        EnvironmentBlockStructureLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        InitialTab                      matlab.ui.container.Tab
        initEnvironment                 matlab.ui.control.DropDown
        initEnvironmentLabel            matlab.ui.control.Label
        initNumTrials                   matlab.ui.control.NumericEditField
        initNumTrialsLabel              matlab.ui.control.Label
        initActive                      matlab.ui.control.CheckBox
        EvenTab                         matlab.ui.container.Tab
        evenTrialsPerBlock              matlab.ui.control.NumericEditField
        evenTrialsPerBlockLabel         matlab.ui.control.Label
        evenBlocks                      matlab.ui.control.CheckBox
        RandomTab                       matlab.ui.container.Tab
        randomLengthMean                matlab.ui.control.NumericEditField
        randomLengthMeanLabel           matlab.ui.control.Label
        randomDistribution              matlab.ui.control.DropDown
        randomDistributionLabel         matlab.ui.control.Label
        randomBlocks                    matlab.ui.control.CheckBox
        CustomTab                       matlab.ui.container.Tab
        customPreview                   matlab.ui.control.Button
        customGenerator                 matlab.ui.control.EditField
        customGeneratorLabel            matlab.ui.control.Label
        customBlocks                    matlab.ui.control.CheckBox
        maxTrialNumber                  matlab.ui.control.NumericEditField
        maxTrialNumberLabel             matlab.ui.control.Label
        sessionOffset                   matlab.ui.control.NumericEditField
        sessionOffsetLabel              matlab.ui.control.Label
        activeStopping                  matlab.ui.control.CheckBox
        activeLicking                   matlab.ui.control.CheckBox
        updateDirectory                 matlab.ui.control.Button
        lickEncoderAvailable            matlab.ui.control.CheckBox
        animalName                      matlab.ui.control.EditField
        animalNameLabel                 matlab.ui.control.Label
        topBox                          matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        userMessages = cell(0,2);
    end
    
    properties (Access = private)
        validDirectory = false; % boolean variable, flips when there's a valid vrDirectory
        environmentInfo = zeros(0,9); % #Frames, RewardPosition, RewardPosType, Order
        vrLength
        vrFrames
        vrFramesDS
        vrDSFactor
        vrRewPos
        vrRewTol
        vrOrder
        vrActive
        envBaseNames = cell(0); % base names for each environment (filename)
        envRichNames = cell(0); % rich names for each environment
        envOptionsLastClick = -inf*ones(1,6); % time of last click on vrEnv option (for double click)
        envOptionsLastSelection = nan; % index of last click on vrEnv option
        envInUseLastClick = -inf*ones(1,6); % time of last click on vrEnv in use
        envInUseLastSelection = nan; % index of last click on vrEnv in use
        doubleClickTime = 0.5; % Time elapsed to consider something a double click
        randRewardArray = [0, 1; 1, 1]; % numeric array of possible reward probabilities (first column P(rew), second Freq)
        rigInfo; % structure containing all rig information (loaded in startup with vrControlRigParameters()
        condTrialReward = [1 1 0]; % Array containing P(rew) for conditional trials
        condTrialActive = [1 0 0]; % Array containing active licking mode for conditional trials
        condTrialGain = [1 1 1]; % Array containing movement gain for conditional trials
        condTrialFreq = [65 25 10]; % Array containing frequency for each conditional trial type
    end
    
    methods (Access = private)
        
        function validateVrDirectory(app,newDirectory,startupSwitch)
            if nargin<3, startupSwitch = false; end
            if exist(newDirectory, 'dir')
                % Make sure vrDirectory ends in fileseparator
                if ~strcmp(newDirectory(end),filesep)
                    newDirectory(end+1) = filesep; 
                end
                app.validDirectory = true;
                app.vrDirectory.Value = vrControlPersistent('vrDirectory', newDirectory);
                activateEnvironmentOptions(app) % this activates all environment options from vrDirectory
                presentMessage(app, 'New vrDirectory selected, options reset throughout app.', 1)
            elseif startupSwitch
                app.validDirectory = false; 
                app.vrDirectory.Value = vrControlPersistent('vrDirectory', '');
            else
                app.validDirectory = false;
                app.vrDirectory.Value = '';
                clearEnvironmentOptions(app) % clear all environment options 
                presentMessage(app, 'Invalid vrDirectory requested, all options disabled.', 2)
            end
        end

        function activateEnvironmentOptions(app)
            if ~app.validDirectory
                presentMessage(app, 'can''t activate environment without valid directory', 2); 
                clearEnvironmentOptions(app)
                return
            end
            % Look for vr environment files and populate menu
            vrExt = standardizeExtension(app);
            vrList = dir(fullfile(app.vrDirectory.Value,['*',vrExt]));
            numEnv = length(vrList);

            % Build Environment Info Array
            app.vrLength = environmentDefaults(app,'length') * ones(numEnv,1);
            app.vrFrames = zeros(numEnv,1);
            app.vrFramesDS = zeros(numEnv,1);
            app.vrDSFactor = environmentDefaults(app,'dsfactor') * ones(numEnv,1);
            app.vrRewPos = environmentDefaults(app,'rewpos') * ones(numEnv,1);
            app.vrRewTol = environmentDefaults(app,'rewtol') * ones(numEnv,1);
            app.vrOrder = environmentDefaults(app,'order') * ones(numEnv,1);
            app.vrActive = environmentDefaults(app,'active') * ones(numEnv,1);
            for vrenv = 1:numEnv
                cpath = fullfile(app.vrDirectory.Value,vrList(vrenv).name);
                ctifinfo = imfinfo(cpath);
                numFrames = length(ctifinfo);
                app.vrFrames(vrenv) = numFrames;
                app.vrFramesDS(vrenv) = numFrames;
            end

            % Update UI
            app.vrenvOptions.Value = {}; % deselect everything
            app.vrenvOptions.Items = repmat({''},numEnv,1);
            app.vrenvOptions.ItemsData = 1:numEnv; 
            app.vrenvInUse.Value = {}; % Deselect everything
            app.vrenvInUse.Items = {'none selected'};
            app.vrenvInUse.ItemsData = nan;
            app.envSettings.Items = repmat({''},numEnv,1);
            app.envSettings.ItemsData = 1:numEnv;
            updateOrderMenu(app,0); % reset order menu
            for vrenv = 1:numEnv
                [~,cname] = fileparts(vrList(vrenv).name);
                app.envBaseNames{vrenv} = cname;
                app.envRichNames{vrenv} = constructRichName(app, vrenv);
                app.vrenvOptions.Items{vrenv} = sprintf('%d - %s',vrenv,cname);
                app.envSettings.Items{vrenv} = cname;
            end

            % Enable user interface for environment settings
            app.vrenvOptions.Enable = "on";
            app.vrenvOptionsLabel.Enable = "on";
            app.vrenvInUse.Enable = "on";
            app.vrenvInUseLabel.Enable = "on";
            app.envSettings.Enable = "on";
            app.envSettingsLabel.Enable = "on";
            app.envLength.Enable = "on";
            app.envLengthLabel.Enable = "on";
            app.dsFactor.Enable = "on";
            app.dsFactorLabel.Enable = "on";
            app.rewardPosition.Enable = "on";
            app.rewardPositionLabel.Enable = "on";
            app.rewardTolerance.Enable = "on";
            app.rewardToleranceLabel.Enable = "on";
            app.envOrder.Enable = "on";
            app.envOrderLabel.Enable = "on";
            app.envActive.Enable = "on";

            updateInitialBlockMenu(app)
        end


        function loadEnvironmentOptions(app)
            % When loading settings, this loads environment options to GUI
            % after loading all the settings data into the app
            numEnv = length(app.vrLength);
            activeEnvironments = any(app.vrActive); 
            
            % Update UI
            app.vrenvOptions.Value = {}; % deselect everything
            app.vrenvOptions.Items = repmat({''},numEnv,1);
            app.vrenvOptions.ItemsData = 1:numEnv; 
            app.vrenvInUse.Value = {}; % Deselect everything
            app.envSettings.Items = repmat({''},numEnv,1);
            app.envSettings.ItemsData = 1:numEnv;
            for vrenv = 1:numEnv
                cname = app.envBaseNames{vrenv};
                app.envRichNames{vrenv} = constructRichName(app, vrenv);
                app.vrenvOptions.Items{vrenv} = sprintf('(%i)- %s',vrenv,cname);
                app.envSettings.Items{vrenv} = cname;
            end
            if activeEnvironments
                idxActive = vrControlReturnOrder(app.vrOrder, app.vrActive);
                app.vrenvInUse.Items = app.envRichNames(idxActive);
                app.vrenvInUse.ItemsData = idxActive;
            else
                app.vrenvInUse.Items = {'none selected'};
                app.vrenvInUse.ItemsData = nan;
            end
            updateOrderMenu(app, activeEnvironments);
            updateSettingsMenus(app,1); % default to selecting first environment (out of options) from settings menu
        end

        function clearEnvironmentOptions(app)
            app.vrLength = [];
            app.vrFrames = [];
            app.vrFramesDS = [];
            app.vrDSFactor = [];
            app.vrRewPos = [];
            app.vrRewTol = [];
            app.vrOrder = [];
            app.vrActive = [];
            app.vrenvOptions.Items = {'path not defined'}; 
            app.vrenvOptions.ItemsData = nan;
            app.vrenvInUse.Items = {'path not defined'};
            app.vrenvOptions.ItemsData = nan;
            app.envSettings.Items = {'path not defined'};
            app.envSettings.ItemsData = nan;
            app.vrenvOptions.Enable = "off";
            app.envSettings.Enable = "off";
            app.envSettingsLabel.Enable = "off";
            app.envLength.Enable = "off";
            app.envLengthLabel.Enable = "off";
            app.envLength.Value = environmentDefaults(app, 'length');
            app.dsFactor.Enable = "off";
            app.dsFactorLabel.Enable = "off";
            app.dsFactor.Value = environmentDefaults(app, 'dsfactor');
            app.rewardPosition.Enable = "off";
            app.rewardPositionLabel.Enable = "off";
            app.rewardPosition.Value = environmentDefaults(app,'rewpos');
            app.rewardTolerance.Enable = "off";
            app.rewardToleranceLabel.Enable = "off";
            app.rewardTolerance.Value = environmentDefaults(app, 'rewtol');
            updateOrderMenu(app, 0)
            app.envOrder.Enable = "off";
            app.envOrderLabel.Enable = "off";
            app.envActive.Enable = "off";
            app.envActive.Value = false;
            updateInitialBlockMenu(app)
        end

        function richEnvName = constructRichName(app, envidx)
            baseName = app.envBaseNames{envidx};
            lengthString = sprintf('Length=%icm',app.vrLength(envidx));
            framesString = sprintf('%i frames -(ds)-> %i',app.vrFrames(envidx),app.vrFramesDS(envidx));
            rewardUnit = 'cm';
            rewardString = sprintf('Reward Position=%.1f +/- %.1f%s', app.vrRewPos(envidx), app.vrRewTol(envidx), rewardUnit);
            richEnvName = [sprintf('(%i)-',envidx),strjoin({baseName,lengthString,framesString,rewardString},' || ')];
        end
        
        function addEnvironment(app,envidx)
            % after environment is double clicked on the options column,
            % this function adds it to the inUse column (if not already)
            if ismember(envidx, app.vrenvInUse.ItemsData)
                presentMessage(app, sprintf('Environment %d is already in use!',envidx),1)
                return
            end
            if any(app.vrActive)
                newIdx = length(app.vrenvInUse.Items) + 1;
            else
                newIdx = 1;
            end
            app.vrOrder(envidx) = newIdx;
            app.vrActive(envidx) = 1;
            updateOrderMenu(app,1);
            app.envRichNames{envidx} = constructRichName(app,envidx);
            app.vrenvInUse.Items{newIdx} = app.envRichNames{envidx};
            app.vrenvInUse.ItemsData(newIdx) = envidx;
            updateInitialBlockMenu(app); 
            updateSettingsMenus(app,envidx); % update settings menus and enable
            presentMessage(app, sprintf('Environment %d now in use.',envidx),1)
        end

        function removeEnvironment(app,envidx)
            % after environment is double clicked on inUse column or
            % envActive toggled, remove environment from everything
            if ~ismember(envidx, app.vrenvInUse.ItemsData)
                error('Environment (%d) is being removed but it is not currently active',envidx); 
            end
            app.vrOrder(envidx) = environmentDefaults(app,'order');
            app.vrActive(envidx) = false;
            updateOrderMenu(app,1);

            activeEnvironments = any(app.vrActive); % reset if last environment
            app.envRichNames{envidx} = constructRichName(app,envidx); % update it's rich name
            if activeEnvironments
                % If there remain active environments, then:
                % Reset order of remaining environments
                idxActive = find(app.vrActive);
                pOrder = app.vrOrder(idxActive);
                [~,pOrdIdx] = sort(pOrder);
                app.vrOrder(idxActive) = pOrdIdx;

                % Repopulate vrenvInUse array with correct order
                app.vrenvInUse.Items = {};
                app.vrenvInUse.ItemsData = [];
                for eidx = 1:length(idxActive)
                    cenv = idxActive(pOrdIdx(eidx));
                    app.envRichNames{cenv} = constructRichName(app,cenv);
                    app.vrenvInUse.Items{eidx} = app.envRichNames{cenv};
                    app.vrenvInUse.ItemsData(eidx) = cenv;
                end
            else
                % Otherwise, clear vrenvInUse section
                app.vrenvInUse.Items = {};
                app.vrenvInUse.ItemsData = [];
            end
            updateInitialBlockMenu(app); 
            updateSettingsMenus(app,envidx); % update settings menus and enable
            presentMessage(app, sprintf('Environment %d is no longer in use.',envidx),1)
        end
        
        function updateSettingsMenus(app,envidx)
            % - length(1)
            % - frames(2) / dsFrames(3) /dsFactor(4)
            % - rewardPosition(5) / rewardTolerance(6) / rewardUnits(7)
            % - order in block(8)
            % - active(9)
            % -- code for updating all the settings menus when the dropdown
            % value changes or when a new environment is selected --
            if app.vrActive(envidx)
                enableString = "on";
                app.envSettings.Value = envidx;
                app.envLength.Value = app.vrLength(envidx);
                app.dsFactor.Value = app.vrDSFactor(envidx);
                app.rewardPosition.Value = 0;
                app.rewardTolerance.Value = 0;
                app.rewardPosition.Limits = [0 app.vrLength(envidx)];
                app.rewardTolerance.Limits = [0 app.vrLength(envidx)];
                app.rewardPosition.Value = app.vrRewPos(envidx);
                app.rewardTolerance.Value = app.vrRewTol(envidx);
                updateOrderMenu(app,1)
                app.envOrder.Value = app.vrOrder(envidx);
                app.envActive.Value = app.vrActive(envidx);
            else
                enableString = "off";
                app.envSettings.Value = envidx;
                app.envLength.Value = environmentDefaults(app,'length'); % default
                app.dsFactor.Value = environmentDefaults(app,'dsfactor');
                app.rewardPosition.Value = 0;
                app.rewardTolerance.Value = 0;
                app.rewardPosition.Limits = [0 environmentDefaults(app,'length')];
                app.rewardTolerance.Limits = [0 environmentDefaults(app,'length')];                    
                app.rewardPosition.Value = environmentDefaults(app,'rewpos');
                app.rewardTolerance.Value = environmentDefaults(app,'rewtol');
                updateOrderMenu(app,0)
                app.envOrder.Value = environmentDefaults(app,'order');
                app.envActive.Value = environmentDefaults(app,'active');
            end
            app.envLength.Enable = enableString;
            app.envLengthLabel.Enable = enableString;
            app.dsFactor.Enable = enableString;
            app.dsFactorLabel.Enable = enableString;
            app.rewardPosition.Enable = enableString;
            app.rewardPositionLabel.Enable = enableString;
            app.rewardTolerance.Enable = enableString;
            app.rewardToleranceLabel.Enable = enableString;
            app.envOrder.Enable = enableString;
            app.envOrderLabel.Enable = enableString;
        end

        function vrExtension = standardizeExtension(app)
            % This makes sure that the extension has a leading period
            vrExtension = app.vrExtension.Value;
            if vrExtension(1)~='.'
                vrExtension = ['.',vrExtension]; 
            end
        end

        function changeBlockStructure(app, newType)
            % This is called every time a different block structure is
            % selected. It enables all settings within the current block
            % structure and disenables all settings from other block
            % structures.
            
            if strcmpi(newType, 'even')
                % If we've switched to the Even Block Structure --
            
                % Enable even block settings
                app.EvenTab.Title = '*Even*';
                app.evenBlocks.Value = true;
                app.evenBlocks.Enable = "off";
                app.evenTrialsPerBlock.Enable="on";
                app.evenTrialsPerBlockLabel.Enable="on";
                
                % Disenable random block settings
                app.RandomTab.Title = 'Random';
                app.randomBlocks.Value = false; 
                app.randomBlocks.Enable = "on";
                app.randomLengthMean.Enable = "off";
                app.randomLengthMeanLabel.Enable = "off";
                app.randomDistribution.Enable = "off";
                app.randomDistributionLabel.Enable = "off";
                
                % Disenable custom block settings
                app.CustomTab.Title = 'Custom';
                app.customBlocks.Value = false;
                app.customBlocks.Enable = "on";
                app.customGenerator.Enable = "off";
                app.customGeneratorLabel.Enable = "off";
                
                
            elseif strcmpi(newType, 'random')
                % If we've switched to the Random Block Structure --

                % Enable even block settings
                app.EvenTab.Title = 'Even';
                app.evenBlocks.Value = false;
                app.evenBlocks.Enable = "on";
                app.evenTrialsPerBlock.Enable="off";
                app.evenTrialsPerBlockLabel.Enable="off";
                
                % Disenable random block settings
                app.RandomTab.Title = '*Random*';
                app.randomBlocks.Value = true; 
                app.randomBlocks.Enable = "off"; 
                app.randomLengthMean.Enable = "on";
                app.randomLengthMeanLabel.Enable = "on";
                app.randomDistribution.Enable = "on";
                app.randomDistributionLabel.Enable = "on";
                
                % Disenable custom block settings
                app.CustomTab.Title = 'Custom';
                app.customBlocks.Value = false;
                app.customBlocks.Enable = "on";
                app.customGenerator.Enable = "off";
                app.customGeneratorLabel.Enable = "off";
            
            elseif strcmpi(newType, 'custom')
                % If we've switched to the Custom Block Structure --
                
                % Disenable even block settings
                app.EvenTab.Title = 'Even';
                app.evenBlocks.Value = false;
                app.evenBlocks.Enable = "on";
                app.evenTrialsPerBlock.Enable="off";
                app.evenTrialsPerBlockLabel.Enable="off";
                
                % Disenable random block settings
                app.RandomTab.Title = 'Random';
                app.randomBlocks.Value = false; 
                app.randomBlocks.Enable = "on"; 
                app.randomLengthMean.Enable = "off";
                app.randomLengthMeanLabel.Enable = "off";
                app.randomDistribution.Enable = "off";
                app.randomDistributionLabel.Enable = "off";
                
                % Disenable custom block settings
                app.CustomTab.Title = '*Custom*';
                app.customBlocks.Value = true;
                app.customBlocks.Enable = "off";
                app.customGenerator.Enable = "on";
                app.customGeneratorLabel.Enable = "on";
                
            else
                error('changeBlockStructure received unknown type!');
            end

            presentMessage(app, sprintf('Block structure changed to %s.',newType), 1);
        end
        
        function presentMessage(app,message,type)
            app.messagestouserTextArea.Value = message;
            if type==1, app.messagestouserTextArea.FontColor = 'k'; end
            if type==2, app.messagestouserTextArea.FontColor = 'r'; end
            messageNumber = size(app.userMessages,1) + 1;
            app.userMessages{messageNumber,1} = message;
            app.userMessages{messageNumber,2} = type;
        end
        
        function envSettingsChanged(app,envidx)
            % Construct rich environment name again
            app.envRichNames{envidx} = constructRichName(app,envidx);
            % If environment is active, then it should be in the inuseList
            % and we need to update the string associated with it
            if app.vrActive(envidx)
                app.vrenvInUse.Items{app.vrOrder(envidx)} = app.envRichNames{envidx};
            end
            
        end
        
        function value = environmentDefaults(~,feature)
            switch lower(feature)
                case 'length', value = 250; % 250cm default length
                case 'dsfactor', value = 1; % default ds factor = no downsampling
                case 'rewpos', value = 200; % in cm
                case 'rewtol', value = 10; % in cm
                case 'rewunit', value = 2; % 1=%, 2=cm
                case 'order', value = -1;
                case 'active', value = 0;
                case 'abslimits', value = [0 2000];
                case 'perlimits', value = [0 100];
                otherwise, error('feature string not recognized!!');
            end            
        end
        
        function updateOrderMenu(app,setting)
            numActive = sum(app.vrActive);
            if logical(setting) && numActive
                app.envOrder.Items = cellfun(@(idx) sprintf('%i',idx), num2cell(1:numActive), 'uni', 0);
                app.envOrder.ItemsData = 1:numActive;
            else
                app.envOrder.Items = {'n/a'};
                app.envOrder.ItemsData = -1;
            end
        end
        
        function updateInitialBlockMenu(app)
            idxActive = find(app.vrActive);
            pValue = app.initEnvironment.Value; % Store previous value
            if ~ismember(app.initEnvironment.Value, idxActive)
                app.initActive.Value = false; 
            end
            if ~isempty(idxActive)
                app.initEnvironment.Items = cellfun(@(eidx) sprintf('%i',eidx), num2cell(idxActive), 'uni', 0);
                app.initEnvironment.ItemsData = idxActive;
            else
                app.initEnvironment.Items = {'n/a'};
                app.initEnvironment.ItemsData = -1;
                app.initEnvironment.Value = -1;
                app.initActive.Value = false;
            end
            if ismember(pValue, idxActive)
                app.initEnvironment.Value = pValue; 
            end

            if app.initNumTrials.Value==0
                app.initActive.Value = false; 
            end

            if app.initActive.Value
                app.InitialTab.Title = '*Initial*';
            else
                app.InitialTab.Title = 'Initial';
            end
        end
        
        function generateRewardProbList(app)
            rewProb = app.randRewardArray(:,1);
            rewFreq = app.randRewardArray(:,2);
            rewPerc = rewFreq / sum(rewFreq) * 100;
            numRew = size(rewProb,1);
            app.rewProbList.Items = repmat({''}, numRew, 1);
            app.rewProbList.ItemsData = 1:numRew;
            for rew = 1:numRew
                rewString = sprintf('P(R)=%.2f, %.1f%%, F=%d',rewProb(rew),rewPerc(rew),rewFreq(rew));
                app.rewProbList.Items{rew} = rewString;
            end
        end

        function validateAnimalSavePath(app,newDirectory)
            if exist(newDirectory, 'dir')
                % Make sure animal save path ends in fileseparator
                if ~strcmp(newDirectory(end),filesep)
                    newDirectory(end+1) = filesep; 
                end
                app.animalSettingsPath.Value = vrControlPersistent('animalSettingsPath', newDirectory);
                presentMessage(app, 'New animal save path defined.', 1)
            else
                app.animalSettingsPath.Value = '';
                presentMessage(app, 'Invalid animal save path requested, choose valid directory.', 2)
            end
        end
        
        function out = generateOutputStructure(app)
            % Meta Parameters
            out.vrDirectory = app.vrDirectory.Value; 
            out.vrExtension = app.vrExtension.Value;
            out.animalName = app.animalName.Value; 
            out.animalSettingsPath = app.animalSettingsPath.Value; 
            out.animalSettingsID = app.animalSettingsID.Value;
            out.sessionOffset = app.sessionOffset.Value; 
            out.activeStopping = app.activeStopping.Value; 
            out.maxTrialNumber = app.maxTrialNumber.Value;
            out.maxTrialDuration = app.maxTrialDuration.Value; 
            
            % VR Directory, Options, & In Use + Settings
            idxActive = vrControlReturnOrder(app.vrOrder, app.vrActive);
            out.vrOptions = app.envBaseNames;
            out.vrInUse = app.envBaseNames(idxActive); % Names of environments in use in order
            out.vrLength = app.vrLength;
            out.vrFrames = app.vrFrames;
            out.vrFramesDS = app.vrFramesDS;
            out.vrDSFactor = app.vrDSFactor;
            out.vrRewPos = app.vrRewPos;
            out.vrRewTol = app.vrRewTol;
            out.vrOrder = app.vrOrder;
            out.vrActive = app.vrActive;
            
            % Intertrial Interval Settings
            out.minimumITI = app.minimumITI.Value; 
            out.randomITI = app.randomizeITI.Value; 
            if out.randomITI
                out.randomMeanITI = app.randomMeanITI.Value; 
                out.distributionITI = app.distributionITI.Value;
                out.distributionNameITI = app.distributionITI.Items{app.distributionITI.Value};
            else
                out.randomMeanITI = []; 
                out.distributionITI = [];
                out.distributionNameITI = '';
            end
            
            % Trial Structure Settings
            out.condReward = app.condProbRew.Value;
            if out.condReward
                out.condTrialReward = app.condTrialReward;
                out.randomReward = false;
                out.customRewardProb = false;
                out.customRewardFunction = '';
                out.randomRewardArray = [];
                out.randomRewardWindow = [];
                out.randomRewardWindowString = '';
            else
                out.condTrialReward = [];
                out.randomReward = app.randReward.Value; 
                if out.randomReward
                    out.customRewardProb = app.customProbReward.Value; 
                    if out.customRewardProb
                        out.customRewardFunction = app.customProbRewardGenerator.Value;
                        out.randomRewardArray = [];
                        out.randomRewardWindow = [];
                        out.randomRewardWindowString = '';
                    else
                        out.customRewardFunction = '';
                        out.randomRewardArray = app.randRewardArray;
                        out.randomRewardWindow = app.randRewardWindow.Value;
                        out.randomRewardWindowString = app.randRewardWindow.Items{app.randRewardWindow.Value};
                    end
                else
                    out.customRewardProb = false;
                    out.customRewardFunction = '';
                    out.randomRewardArray = [];
                    out.randomRewardWindow = [];
                    out.randomRewardWindowString = '';
                end
            end

            % Trial Structure Active Licking
            out.condActive = app.condActiveLick.Value;
            if out.condActive
                out.condTrialActive = app.condTrialActive;
                out.lickEncoderAvailable = app.lickEncoderAvailable.Value;
                out.activeLicking = app.activeLicking.Value;
            else
                out.condTrialActive = [];
                out.lickEncoderAvailable = app.lickEncoderAvailable.Value; 
                out.activeLicking = app.activeLicking.Value; 
            end

            % Trial Structure Movement Gain
            out.condGain = app.condMvmtGain.Value;
            if out.condGain
                out.condTrialGain = app.condTrialGain.Value;
                out.movementGain = [];
                out.randomGain = false;
                out.customGain = false;
                out.customGainFunction = '';
                out.minGain = [];
                out.maxGain = [];
                out.randGainWindow = [];
                out.randGainWindowString = '';
            else
                out.condTrialGain = [];
                out.movementGain = app.movementGain.Value;
                out.randomGain = app.randGain.Value;
                if out.randomGain
                    out.customGain = app.customGain.Value; 
                    if out.customGain
                        out.customGainFunction = app.customGainGenerator.Value;
                        out.minGain = [];
                        out.maxGain = [];
                        out.randGainWindow = [];
                        out.randGainWindowString = '';
                    else
                        out.customGainFunction = '';
                        out.minGain = app.minGain.Value;
                        out.maxGain = app.maxGain.Value;
                        out.randGainWindow = app.randGainWindow.Value;
                        out.randGainWindowString = app.randGainWindow.Items{app.randGainWindow.Value};
                    end
                else
                    out.customGain = false;
                    out.customGainFunction = '';
                    out.minGain = [];
                    out.maxGain = [];
                    out.randGainWindow = [];
                    out.randGainWindowString = '';
                end
            end

            if out.condReward || out.condActive || out.condGain
                out.condTrialFreq = app.condTrialFreq;
            else
                out.condTrialFreq = [];
            end

            % Initial Block Settings
            out.initBlock = app.initActive.Value; 
            if out.initBlock
                out.initTrials = app.initNumTrials.Value;
                out.initEnvIdx = app.initEnvironment.Value; 
                out.initEnvName = app.envBaseNames{out.initEnvIdx};
            else
                out.initTrials = [];
                out.initEnvIdx = []; 
                out.initEnvName = '';
            end

            % Block Structure Settings
            out.blockTypeNames = {'Even','Random','Custom'};
            out.blockType = [app.evenBlocks.Value, app.randomBlocks.Value, app.customBlocks.Value];
            if sum(out.blockType)~=1, error('Somehow the block type wasn''t clear. Multiple were selected.'); end
            % Initialize all possibilities, then set whatever is relevant
            out.blockTrialsPer = []; 
            out.blockRandomMean = [];
            out.blockRandomDistribution = [];
            out.blockRandomDistributionName = '';
            out.blockCustomFunction = '';
            if out.blockType(1)
                out.blockTrialsPer = app.evenTrialsPerBlock.Value; % Even Blocks
            end
            if out.blockType(2)
                out.blockRandomMean = app.randomLengthMean.Value; % Random Blocks
                out.blockRandomDistribution = app.randomDistribution.Value;
                out.blockRandomDistributionName = app.randomDistribution.Items{app.randomDistribution.Value};
            end
            if out.blockType(3)
                out.blockCustomFunction = app.customGenerator.Value; % Custom Blocks
            end
        end
        
        function highlightComponent(app, component, property)
            if nargin < 3, property = 'BackgroundColor'; end
            if ~iscell(component), component = {component}; end
            numComponent = length(component);
            for nc = 1:numComponent
                cComponent = component{nc};
                if ~any(strcmp(fields(app),cComponent)), error('Requested component (%s) not a field of the app!', component{nc}); end
                if ~any(strcmp(fields(app.(cComponent)),property)), error('Requested component (%s) doesn''t have a %s!',component{nc}, property); end
            end
            timing = 0.05; % How long to wait before each flip
            numFlips = 3; % Number of flips to red
            gentleRed = [1,0.8,0.8]; % Red color to flip background to
            highlightColors = repmat({gentleRed},numComponent,2);
            for nc = 1:numComponent
                % Store previous color
                highlightColors{nc,1} = app.(component{nc}).(property);
            end
            for idx = 2:2*numFlips+1
                % Do flips
                for nc = 1:numComponent
                    app.(component{nc}).(property) = highlightColors{nc,mod(idx-1,2)+1};
                end
                pause(timing);
            end
        end

        function updateConditionalStructure(app)
            numTrialTypes = length(app.condTrialReward);
            if length(app.condTrialActive) ~= numTrialTypes || length(app.condTrialGain) ~= numTrialTypes || length(app.condTrialFreq) ~= numTrialTypes
                error('Conditional trial vectors don''t have same length...');
            end

            condRewardOn = app.condProbRew.Value;
            condActiveOn = app.condActiveLick.Value;
            condGainOn = app.condMvmtGain.Value;
            if condRewardOn || condActiveOn || condGainOn
                app.ConditionalTrialTypesTab.Title = '*Conditional Trial Types*';
            else
                app.ConditionalTrialTypesTab.Title = 'Conditional Trial Types';
            end

            if condRewardOn
                % Then turn off the independent control of p(reward)
                enableString = "off";
                app.rewProbNewFreq.Enable = enableString;
                app.rewProbNewProb.Enable = enableString;
                app.rewProbRemove.Enable = enableString;
                app.rewProbAddNew.Enable = enableString;
                app.rewProbNewFreqLabel.Enable = enableString;
                app.rewProbNewProbLabel.Enable = enableString;
                app.rewProbList.Enable = enableString;
                app.customProbReward.Enable = enableString;
                app.customProbRewardGenerator.Enable = enableString;
                app.randRewardWindow.Enable = enableString;
                app.randReward.Enable = enableString;
            else
                % Then turn on independent control of p(reward)
                app.randReward.Enable = "on";
                randRewardValueChanged(app)
                if app.randReward.Value
                    customProbRewardValueChanged(app)
                end
            end

            if condActiveOn
                % Turn off independent control of activeLicking
                app.activeLicking.Enable = "off";
                app.lickEncoderAvailable.Enable = "off";
            else
                % Turn on independent control of activeLicking
                app.activeLicking.Enable = "on";
                app.lickEncoderAvailable.Enable = "on";
            end

            if condGainOn
                % Turn off independent control of movement gain
                enableString = "off";
                app.movementGain.Enable = enableString;
                app.maxGain.Enable = enableString;
                app.maxGainLabel.Enable = enableString;
                app.minGain.Enable = enableString;
                app.minGainLabel.Enable = enableString;
                app.customGainGenerator.Enable = enableString;
                app.customGain.Enable = enableString;
                app.randGainWindow.Enable = enableString;
                app.randGain.Enable = enableString;
            else
                % Turn on independent control of movement gain
                app.randGain.Enable = "on";
                randGainValueChanged(app)
                if app.randGain.Value
                    customGainValueChanged(app)
                end
            end
            generateCondTrialList(app)            
        end
        
        function generateCondTrialList(app)
            condRewardOn = app.condProbRew.Value;
            condActiveOn = app.condActiveLick.Value;
            condGainOn = app.condMvmtGain.Value;
            
            if condRewardOn || condActiveOn || condGainOn

                condPerc = app.condTrialFreq / sum(app.condTrialFreq) * 100; 
                numCond = length(app.condTrialReward);
                app.condTrialList.Items = repmat({''}, numCond, 1);
                app.condTrialList.ItemsData = 1:numCond;
                for cond = 1:numCond
                    if condRewardOn
                        rewString = sprintf('P(R)=%.2f',app.condTrialReward(cond));
                        if condActiveOn || condGainOn, rewPost = ', '; else, rewPost = ''; end
                    else
                        rewString = '';
                        rewPost = '';
                    end
                    if condActiveOn
                        activeString = sprintf('AL=%d',app.condTrialActive(cond));
                        if condGainOn, activePost = ', '; else, activePost = ''; end
                    else
                        activeString = '';
                        activePost = '';
                    end
                    if condGainOn
                        gainString = sprintf('MG=%.2f',app.condTrialGain(cond));
                    else
                        gainString = sprintf('');
                    end
                    fullString = sprintf('%s%s%s%s%s, %.1f%%, F=%d',...
                        rewString,rewPost,activeString,activePost,gainString,condPerc(cond),app.condTrialFreq(cond));
                    app.condTrialList.Items{cond} = fullString;
                end
            else
                app.condTrialList.Items = {'conditional trial types off'};
                app.condTrialList.ItemsData = 1;
            end
        end
        
        function trialStructure = generateTrialStructure(app)
            % How many trials
            trialStructure.maxTrials = app.maxTrialNumber.Value;
            trialStructure.maxDuration = app.maxTrialDuration.Value;
                        
            % Inline for getting path to vrEnv files
            trialStructure.getEnvName = @(idx) app.envBaseNames{idx};
            trialStructure.getEnvPath = @(idx) fullfile(app.vrDirectory.Value, ...
                sprintf('%s%s',app.envBaseNames{idx},app.vrExtension.Value));
            
            % Initial block (does nothing if there isn't one)
            initBlockLength = 1*(app.initActive.Value * app.initNumTrials.Value);
            trialStructure.envIndex = nan(trialStructure.maxTrials,1);
            trialStructure.envIndex(1:initBlockLength) = app.initEnvironment.Value;
            
            % Keep this here so I know to fix it up when(if) I add it
            if app.customBlocks.Value, error('custom block structure note coded yet'); end
            
            % Prepare environment selection across block structure
            blockNames = {'even', 'random'};
            blockTypeActive = [app.evenBlocks.Value, app.randomBlocks.Value];
            if sum(blockTypeActive)~=1, error('One block type should be selected'); end
            blockType = blockNames{blockTypeActive};
            switch blockType
                case 'even'
                    getBlockLength = @() app.evenTrialsPerBlock.Value;
                case 'random'
                    if strcmpi(app.randomDistribution.Items{app.randomDistribution.Value},'poisson')
                        getBlockLength = @() poissrnd(app.randomLengthMean.Value);
                    else
                        error('didn''t recognize random block length distribution');
                    end
                otherwise
                    error('earlier error message is logically broken...');
            end

            % Perform environment selection
            idxActive = vrControlReturnOrder(app.vrOrder, app.vrActive);
            cEnvIdx = 1;
            cTrial = initBlockLength;
            while cTrial < trialStructure.maxTrials
                cBlockLength = getBlockLength();
                excessTrials = max(cTrial + cBlockLength - trialStructure.maxTrials, 0);
                cBlockLength = cBlockLength - excessTrials;
                trialStructure.envIndex(cTrial + (1:cBlockLength)) = idxActive(cEnvIdx);
                cEnvIdx = mod((cEnvIdx+1)-1, length(idxActive))+1; 
                cTrial = cTrial + cBlockLength;
            end
            
            % Associate with length, reward position & tolerance
            trialStructure.roomLength = app.vrLength(trialStructure.envIndex);
            trialStructure.rewardPosition = app.vrRewPos(trialStructure.envIndex);
            trialStructure.rewardTolerance = app.vrRewTol(trialStructure.envIndex);

            % Predetermine intertrial interval
            trialStructure.intertrialInterval = app.minimumITI.Value * ones(trialStructure.maxTrials,1);
            if app.randomizeITI.Value
                if strcmpi(app.distributionITI.Items{app.distributionITI.Value},'uniform')
                    additionalITI = 2*app.randomMeanITI.Value*rand(trialStructure.maxTrials,1);
                    trialStructure.intertrialInterval = trialStructure.intertrialInterval + additionalITI;
                elseif strcmpi(app.distributionITI.Items{app.distributionITI.Value},'exponential')
                    additionalITI = exprnd(app.randomMeanITI.Value,trialStructure.maxTrials,1);
                    trialStructure.intertrialInterval = trialStructure.intertrialInterval + additionalITI;
                else
                    error('Didn''t recognize ITI distribution type');
                end
            end

            % Prepare trial structure selection
            % -- there's always a value for each of the possible
            % conditional trial variables, so let's just generate the trial
            % structure as if it was fully conditional, then overwrite
            % things when they're independent --
            numCond = length(app.condTrialReward);
            condFreq = app.condTrialFreq;
            if any(mod(condFreq,1)), error('condTrialFreqs should be integers'); end
            toSelect = cell2mat(cellfun(@(freq,idx) ...
                idx*ones(1,freq), num2cell(condFreq), num2cell(1:numCond), 'uni', 0));
            condTrialIdx = randsample(toSelect, trialStructure.maxTrials, true);
            
            trialStructure.probReward = app.condTrialReward(condTrialIdx)';
            trialStructure.activeLick = app.condTrialActive(condTrialIdx)';
            trialStructure.mvmtGain = app.condTrialGain(condTrialIdx)';
            trialStructure.activeStop = app.activeStopping.Value * ones(trialStructure.maxTrials,1);
            if ~app.condProbRew.Value
                % Overwrite probReward with independent generation
                if app.randReward.Value
                    if app.customProbReward.Value
                        error('Haven''t implemented any custom functions yet...');
                    else
                        numReward = size(app.randRewardArray,1);
                        randRewFreq = app.randRewardArray(:,2);
                        if any(mod(randRewFreq,1)), error('Random Reward Frequencies should be integers'); end
                        toSelect = cell2mat(cellfun(@(freq,idx) ...
                            idx*ones(1,freq), num2cell(randRewFreq), num2cell(1:numReward), 'uni', 0));
                        randRewIdx = randsample(toSelect, trialStructure.maxTrials, true);
                        trialStructure.probReward = app.randRewardArray(randRewIdx,1);
                    end
                else
                    trialStructure.probReward = ones(trialStructure.maxTrials,1);
                end
            end
            if ~app.condActiveLick.Value
                trialStructure.activeLick = app.activeLicking.Value * ones(trialStructure.maxTrials,1);
            end
            if ~app.condMvmtGain.Value
                if app.randGain.Value
                    if app.customGain.Value
                        error('Haven''t implemented any custom functions yet...');
                    else
                        gainRange = sort([app.minGain.Value, app.maxGain.Value]);
                        trialStructure.mvmtGain = app.movementGain.Value * (diff(gainRange)*rand(trialStructure.maxTrials,1) + gainRange(1));
                    end
                else
                    trialStructure.mvmtGain = app.movementGain.Value * ones(trialStructure.maxTrials,1);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.rigInfo = vrControlRigParameters();
            
            % Do this upon startup to make sure it's consistent
            generateRewardProbList(app)
            generateCondTrialList(app)
            validateVrDirectory(app, vrControlPersistent('vrDirectory'), true);
            app.animalSettingsPath.Value = vrControlPersistent('animalSettingsPath',app.rigInfo.dirSave);
            app.animalName.Value = vrControlPersistent('animalName');
        end

        % Value changed function: vrDirectory
        function vrDirectoryValueChanged(app, event)
            validateVrDirectory(app, app.vrDirectory.Value)
        end

        % Button pushed function: updateDirectory
        function updateDirectoryPushed(app, event)
            validateVrDirectory(app, uigetdir())
        end

        % Value changed function: vrExtension
        function vrExtensionValueChanged(app, event)
            genericExtensionSwitch = false;
            if genericExtensionSwitch
                app.vrExtension.Value = standardizeExtension(app); %#ok -- see string in presentMessage() in below else statement
                [~,~,fext] = fileparts(app.vrExtension.Value);
                if ~strcmp(fext, app.vrExtension.Value)
                    presentMessage(app,'Must provide valid file extension, reverting to default!',2);
                    app.vrExtension.Value = '.tif';
                    vrExtensionValueChanged(app,event) % Run again for certainty...
                end
            else
                app.vrExtension.Value = '.tif';
                presentMessage(app,'The only valid extension is .tif, will update to make generic in the future!', 1);
            end
        end

        % Value changed function: maxTrialNumber
        function maxTrialNumberValueChanged(app, event)
            app.initNumTrials.Limits = [0, app.maxTrialNumber.Value];
        end

        % Value changed function: envActive
        function envActiveValueChanged(app, event)
            envidx = app.envSettings.Value;
            app.vrActive(envidx) = app.envActive.Value;
            if app.vrActive(envidx)
                addEnvironment(app,envidx);
            else
                removeEnvironment(app,envidx);
            end
        end

        % Value changed function: vrenvOptions
        function vrenvOptionsValueChanged(app, event)
            clickTime = clock; % Time of selection
            selection = event.Value; % New list of values (should be a numeric index)
            updateSettingsMenus(app,selection)
            
            % If same option selected and double click, flip value
            timeSinceLast = clickTime - app.envOptionsLastClick;
            wasDoubleClick = ~any(timeSinceLast(1:5)) && timeSinceLast(6) < app.doubleClickTime;
            if selection==app.envOptionsLastSelection && wasDoubleClick
                addEnvironment(app,selection); % add environment to inUse listbox
            end

            % Deselect everything by default
            app.vrenvOptions.Value = []; 
            
            % Update Double Click Parameters
            app.envOptionsLastClick = clickTime;
            app.envOptionsLastSelection = selection;
        end

        % Value changed function: vrenvInUse
        function vrenvInUseValueChanged(app, event)
            clickTime = clock; % Time of selection
            selection = event.Value; % New list of values (should be a numeric index)
            updateSettingsMenus(app,selection)
            
            % If same option selected and double click, flip value
            timeSinceLast = clickTime - app.envInUseLastClick;
            wasDoubleClick = ~any(timeSinceLast(1:5)) && timeSinceLast(6) < app.doubleClickTime;
            if selection==app.envInUseLastSelection && wasDoubleClick
                removeEnvironment(app,selection); % add environment to inUse listbox
            end

            % Deselect everything by default
            app.vrenvInUse.Value = {}; 
            
            % Update Double Click Parameters
            app.envInUseLastClick = clickTime;
            app.envInUseLastSelection = selection;            
        end

        % Value changed function: envSettings
        function envSettingsValueChanged(app, event)
            updateSettingsMenus(app, event.Value);            
        end

        % Value changed function: envLength
        function envLengthValueChanged(app, event)
            envidx = app.envSettings.Value;
            pLength = app.vrLength(envidx);
            pPosition = app.vrRewPos(envidx);
            pTolerance = app.vrRewTol(envidx);
            nPosition = pPosition * app.envLength.Value / pLength;
            nTolerance = pTolerance * app.envLength.Value / pLength;
            app.vrRewPos(envidx) = nPosition;
            app.vrRewTol(envidx) = nTolerance;
            app.vrLength(envidx) = app.envLength.Value;
            updateSettingsMenus(app,envidx);
            envSettingsChanged(app,envidx) % propagate new setting throughout GUI
            presentMessage(app,sprintf('Length changed to %dcm for environment %d. Note that reward position & tolerance change relatively with length!',app.envLength.Value,envidx),1)
        end

        % Value changed function: dsFactor
        function dsFactorValueChanged(app, event)
            % first check if dsFactor leads to integer frames
            envidx = app.envSettings.Value;
            prevValue = event.PreviousValue;
            newValue = event.Value;
            frames = app.vrFrames(envidx);
            dsFrames = frames/newValue;
            if mod(dsFrames,1) ~= 0
                app.dsFactor.Value = prevValue;
                presentMessage(app,sprintf('dsFactor must divide %d frames for environment %d!',frames,envidx),2)
            else
                app.vrDSFactor(envidx) = newValue;
                app.vrFramesDS(envidx) = dsFrames;
                envSettingsChanged(app,envidx);
                presentMessage(app,sprintf('dsFactor changed to %d for environment %d, new frames=%d',newValue,envidx,dsFrames),1)
            end
        end

        % Value changed function: rewardPosition
        function rewardPositionValueChanged(app, event)
            envidx = app.envSettings.Value;
            app.vrRewPos(envidx) = app.rewardPosition.Value;
            envSettingsChanged(app,envidx);
            rewUnits = 'cm';
            presentMessage(app,sprintf('Reward position changed to %d%s for environment %d',app.rewardPosition.Value,rewUnits,envidx),1)
        end

        % Value changed function: rewardTolerance
        function rewardToleranceValueChanged(app, event)
            envidx = app.envSettings.Value;
            app.vrRewTol(envidx) = app.rewardTolerance.Value;
            envSettingsChanged(app,envidx);
            rewUnits = 'cm';
            presentMessage(app,sprintf('Reward tolerance changed to %d%s for environment %d',app.rewardTolerance.Value,rewUnits,envidx),1)
        end

        % Value changed function: envOrder
        function envOrderValueChanged(app, event)
            envidx = app.envSettings.Value; % current environment in settings 
            envorder = app.envOrder.Value; % new order for current environment

            idxActive = find(app.vrActive); % index of all active
            idxSelected = find(idxActive==envidx); % index of current environment among currently active
            prevOrder = app.vrOrder(idxActive); % previous order of all active
            
            newOrder = prevOrder; % setup new order array
            newOrder(idxSelected) = envorder; % update selected environment's order

            % Only one of these will happen...
            idxAboveOld = prevOrder>prevOrder(idxSelected) & prevOrder<=envorder;
            idxBelowOld = prevOrder<prevOrder(idxSelected) & prevOrder>=envorder;
            newOrder(idxAboveOld) = newOrder(idxAboveOld) - 1;
            newOrder(idxBelowOld) = newOrder(idxBelowOld) + 1;
            if any(idxAboveOld) && any(idxBelowOld)
                error('both these have trues!!');
            end

            % Update order!
            app.vrOrder(idxActive) = newOrder;
            if length(unique(app.vrOrder(idxActive))) < length(idxActive)
                error('order repeated somewhere');
            end
            
            % Update in use menu
            [~,newOrderIdx] = sort(newOrder);
            for env = 1:length(newOrderIdx)
                cenv = idxActive(newOrderIdx(env));
                app.vrenvInUse.Items{env} = app.envRichNames{cenv};
                app.vrenvInUse.ItemsData(env) = cenv;
            end

            % And update current environment's settings menu
            updateSettingsMenus(app,envidx) 
        end

        % Value changed function: evenBlocks
        function evenBlocksValueChanged(app, event)
            if app.evenBlocks.Value==0
                error('this should never be 0 after a user interaction');
            end
            changeBlockStructure(app, 'even')
        end

        % Value changed function: randomBlocks
        function randomBlocksValueChanged(app, event)
            if app.randomBlocks.Value==0
                error('this should never be 0 after a user interaction');
            end
            changeBlockStructure(app, 'random')
        end

        % Value changed function: customBlocks
        function customBlocksValueChanged(app, event)
            if app.customBlocks.Value==0
                error('this should never be 0 after a user interaction');
            end
            changeBlockStructure(app, 'custom')            
        end

        % Button pushed function: printenvironmentinfoButton
        function printenvironmentinfoButtonPushed(app, event)
            filename = string(app.envBaseNames)';
            length = string(cellfun(@(length) sprintf('%dcm',length), num2cell(app.vrLength), 'uni', 0));
            rewPos = string(cellfun(@(rewpos) sprintf('%dcm',rewpos), num2cell(app.vrRewPos), 'uni', 0));
            rewTol = string(cellfun(@(rewtol) sprintf('%dcm',rewtol), num2cell(app.vrRewTol), 'uni', 0));
            
            frames = app.vrFrames;
            dsFrames = app.vrFramesDS;
            ds_factor = app.vrDSFactor;
            
            order = app.vrOrder;
            active = app.vrActive;
            tbl = table(filename,length,rewPos,rewTol,frames,dsFrames,ds_factor,order,active);
            disp(tbl);

        end

        % Callback function
        function printappvrDirectoryButtonPushed(app, event)
            display(app.vrDirectory.Value)
            [pth,name,ext] = fileparts(app.vrDirectory.Value);
            fprintf('Path: %s, name: %s, ext: %s \n', pth,name,ext);
        end

        % Value changed function: initActive
        function initActiveValueChanged(app, event)
           updateInitialBlockMenu(app)            
        end

        % Value changed function: initNumTrials
        function initNumTrialsValueChanged(app, event)
            updateInitialBlockMenu(app)
        end

        % Callback function
        function initBlockFeaturesButtonPushed(app, event)
            disp('activeValue');
            display(app.initActive.Value);
            disp('envValue');
            display(app.initEnvironment.Value);
            disp('envItems');
            display(app.initEnvironment.Items);
            disp('envItemsData')
            display(app.initEnvironment.ItemsData);
            disp('envNumTrials')
            display(app.initNumTrials.Value);
            disp('envNumTrialsLimits')
            display(app.initNumTrials.Limits);
        end

        % Value changed function: lickEncoderAvailable
        function lickEncoderAvailableValueChanged(app, event)
            if app.lickEncoderAvailable.Value
                app.activeLicking.Enable = "on"; % make sure it's interactive
            else
                if app.condActiveLick.Value
                    app.lickEncoderAvailable.Value = true; 
                    presentMessage(app,'NOTE: Conditional Trial Types include active licking, must deactivate this before turning off lick availability...', 2);
                    return
                end
                app.activeLicking.Value = false; % force off
                app.activeLicking.Enable = "off"; % don't allow interactivity
            end
        end

        % Value changed function: customGenerator
        function customGeneratorValueChanged(app, event)
            if isempty(which(app.customGenerator.Value))
                presentMessage(app, 'Requested function is not on path. Rejecting it.', 2);
                app.customGenerator.Value = ''; 
            else
                presentMessage(app, sprintf('%s will be used for custom block structure.',app.customGenerator.Value),1);
            end
        end

        % Button pushed function: customPreview
        function customPreviewButtonPushed(app, event)
            fprintf(2,'This feature isn''t ready!!!\n');
        end

        % Value changed function: sessionOffset
        function sessionOffsetValueChanged(app, event)
            if round(app.sessionOffset.Value / 100) * 100 ~= app.sessionOffset.Value
                presentMessage(app, sprintf('Session offset set to %i. It''s a ~very~ good idea to set this to a multiple of 100!',app.sessionOffset.Value), 2);
            end
        end

        % Value changed function: randomizeITI
        function randomizeITIValueChanged(app, event)
            if app.randomizeITI.Value, enableString = "on"; else, enableString = "off"; end
            app.distributionITI.Enable = enableString;
            app.randomMeanITI.Enable = enableString;
            app.randomMeanITILabel.Enable = enableString;
        end

        % Value changed function: randGain
        function randGainValueChanged(app, event)
            if app.randReward.Value || app.randGain.Value
                app.IndependentPRewardMovementGainTab.Title = '*Independent P(Reward) & Movement Gain*';
            else
                app.IndependentPRewardMovementGainTab.Title = 'Independent P(Reward) & Movement Gain';
            end
            if app.randGain.Value, enableString = "on"; else, enableString = "off"; end
            app.maxGain.Enable = enableString;
            app.maxGainLabel.Enable = enableString;
            app.minGain.Enable = enableString;
            app.minGainLabel.Enable = enableString;
            app.randGainWindow.Enable = enableString;
            app.customGain.Enable = enableString;
            app.customGainGenerator.Enable = enableString;
        end

        % Value changed function: randReward
        function randRewardValueChanged(app, event)
            if app.randReward.Value || app.randGain.Value
                app.IndependentPRewardMovementGainTab.Title = '*Independent P(Reward) & Movement Gain*';
            else
                app.IndependentPRewardMovementGainTab.Title = 'Independent P(Reward) & Movement Gain';
            end
            if app.randReward.Value, enableString = "on"; else, enableString = "off"; end
            app.rewProbRemove.Enable = enableString;
            app.rewProbAddNew.Enable = enableString;
            app.rewProbList.Enable = enableString;
            app.rewProbNewFreq.Enable = enableString;
            app.rewProbNewFreqLabel.Enable = enableString;
            app.rewProbNewProb.Enable = enableString;
            app.rewProbNewProbLabel.Enable = enableString;
            app.randRewardWindow.Enable = enableString;
            app.customProbRewardGenerator.Enable = enableString;
            app.customProbReward.Enable = enableString;
        end

        % Callback function
        function probRewardArrayValueChanged(app, event)
            str = app.probRewardArray.Value;
            app.randRewardArray = str2double(strsplit(strjoin(regexp(str,'(-)?\d+(\.\d+)?(e(-|+)\d+)?','match'),' ')));
            if any(app.randRewardArray<0) || any(app.randRewardArray>0)
                presentMessage(app, 'Random reward probability array must contain numbers in the set [0,1]', 2);
                app.probRewardArray.Value = '';
                app.randRewardArray = [];
            end
        end

        % Value changed function: customGainGenerator
        function customGainGeneratorValueChanged(app, event)
            if ~isempty(which(app.customGainGenerator.Value))
                presentMessage(app, 'Custom gain generator function is on path, but there''s currently no way of validating it!', 1);
            else
                app.customGain.Value = false;
                app.customGainGenerator.Value = '';
                app.minGain.Enable = "on";
                app.minGainLabel.Enable = "on";
                app.maxGain.Enable = "on";
                app.maxGainLabel.Enable = "on";
                app.randGainWindow.Enable = "on";
                presentMessage(app, 'Must provide custom gain generator function that is on matlab path to use custom gain!', 2);
                highlightComponent(app,'customGainGenerator');
            end
        end

        % Value changed function: customProbRewardGenerator
        function customProbRewardGeneratorValueChanged(app, event)
            if ~isempty(which(app.customProbRewardGenerator.Value))
                presentMessage(app, 'Custom p(reward) generator function is on path, but there''s currently no way of validating it!', 1);
            else
                app.customProbReward.Value = false;
                app.customProbRewardGenerator.Value = '';
                enableString = "on";
                app.rewProbRemove.Enable = enableString;
                app.rewProbAddNew.Enable = enableString;
                app.rewProbList.Enable = enableString;
                app.rewProbNewFreq.Enable = enableString;
                app.rewProbNewFreqLabel.Enable = enableString;
                app.rewProbNewProb.Enable = enableString;
                app.rewProbNewProbLabel.Enable = enableString;
                app.randRewardWindow.Enable = enableString;
                presentMessage(app, 'Must provide custom p(reward) generator function that is on matlab path to use custom p(reward)!', 2);
                highlightComponent(app,'customProbRewardGenerator');
            end
        end

        % Value changed function: customGain
        function customGainValueChanged(app, event)
            if app.customGain.Value
                if ~isempty(which(app.customGainGenerator.Value))
                    presentMessage(app, 'Switching to custom gain generator', 1);
                    enableString = "off";
                else
                    app.customGain.Value = false;
                    presentMessage(app, 'Can''t switch to custom gain generator without function that is on matlab path', 2);
                    highlightComponent(app, 'customGainGenerator');
                    enableString = "on";
                end
            else
                presentMessage(app, 'Switching to random uniform gain', 1);
                enableString = "on";
            end
            app.minGain.Enable = enableString;
            app.minGainLabel.Enable = enableString;
            app.maxGain.Enable = enableString;
            app.maxGainLabel.Enable = enableString;
            app.randGainWindow.Enable = enableString;
        end

        % Value changed function: customProbReward
        function customProbRewardValueChanged(app, event)
            if app.customProbReward.Value
                if ~isempty(which(app.customProbRewardGenerator.Value))
                    presentMessage(app, 'Switching to custom p(reward) generator', 1);
                    enableString = "off";
                else
                    app.customProbReward.Value = false;
                    presentMessage(app, 'Can''t switch to custom p(reward) generator without function that is on matlab path', 2);
                    highlightComponent(app, 'customProbRewardGenerator');
                    enableString = "on";
                end
            else
                presentMessage(app, 'Switching to random p(reward) from array', 1);
                enableString = "on";
            end
            app.rewProbRemove.Enable = enableString;
            app.rewProbAddNew.Enable = enableString;
            app.rewProbList.Enable = enableString;
            app.rewProbNewFreq.Enable = enableString;
            app.rewProbNewFreqLabel.Enable = enableString;
            app.rewProbNewProb.Enable = enableString;
            app.rewProbNewProbLabel.Enable = enableString;
            app.randRewardWindow.Enable = enableString;
        end

        % Button pushed function: rewProbAddNew
        function rewProbAddNewButtonPushed(app, event)
            newProb = app.rewProbNewProb.Value; 
            newFreq = app.rewProbNewFreq.Value;
            if ismember(newProb,app.randRewardArray(:,1))
                % Then the requested reward probability is already in list
                % Default behavior is to add the frequency
                idxExisting = find(ismember(app.randRewardArray(:,1), newProb));
                app.randRewardArray(idxExisting,2) = app.randRewardArray(idxExisting,2) + newFreq;
                presentMessage(app, 'New reward probability already in list-- added to it''s frequency', 1);
            else
                app.randRewardArray(end+1,:) = [newProb newFreq];
                presentMessage(app, 'New reward probability option added to list', 1);
            end
            generateRewardProbList(app)
        end

        % Button pushed function: rewProbRemove
        function rewProbRemoveButtonPushed(app, event)
            cIndex = app.rewProbList.Value; 
            if isempty(cIndex)
                presentMessage(app, 'Can''t remove if none selected', 2);
                return
            end
            if size(app.randRewardArray,1) == 1
                presentMessage(app, 'Can''t remove last option', 2);
                return
            end
            app.randRewardArray(cIndex,:) = [];
            generateRewardProbList(app)
            presentMessage(app, 'Removed reward probability from list', 1);
        end

        % Button pushed function: saveSettings
        function saveSettingsPushed(app, event)
            if isempty(app.animalSettingsPath.Value)
                presentMessage(app, 'Can''t save settings without defined animal save path', 2);
                highlightComponent(app, 'animalSettingsPath');
                return
            end
            if isempty(app.animalName.Value)
                presentMessage(app, 'Can''t save settings without defined animal name', 2);
                highlightComponent(app, 'animalName');
                return
            end
            settings = generateOutputStructure(app);
            savePath = fullfile(app.animalSettingsPath.Value,sprintf('vrControlSettings_%s_%d.mat',app.animalName.Value,app.animalSettingsID.Value));
            save(savePath, 'settings');
            presentMessage(app, sprintf('Settings saved at: %s', savePath), 1);
        end

        % Button pushed function: loadSettings
        function loadSettingsButtonPushed(app, event)
            loadPath = fullfile(app.animalSettingsPath.Value,sprintf('vrControlSettings_%s_%d.mat',app.animalName.Value,app.animalSettingsID.Value));
            if ~exist(loadPath,'file')
                presentMessage(app, 'Animal settings does not exist at: %s, check animal save path and animal name!', 2);
                highlightComponent(app, {'animalName','animalSettingsPath'});
                return
            end
            loadedSettings = load(loadPath,'settings');
            settings = loadedSettings.settings;
            app.vrDirectory.Value = vrControlPersistent('vrDirectory',settings.vrDirectory);
            app.vrExtension.Value = vrControlPersistent('vrExtension',settings.vrExtension);
            app.animalName.Value = vrControlPersistent('animalName',settings.animalName);
            app.animalSettingsPath.Value = vrControlPersistent('animalSettingsPath',settings.animalSettingsPath);
            app.animalSettingsID.Value = vrControlPersistent('animalSettingsID',settings.animalSettingsID);
            app.sessionOffset.Value = vrControlPersistent('sessionOffset',settings.sessionOffset);
            app.activeStopping.Value = vrControlPersistent('activeStopping',settings.activeStopping);
            app.maxTrialNumber.Value = vrControlPersistent('maxTrialNumber',settings.maxTrialNumber);
            app.maxTrialDuration.Value = vrControlPersistent('maxTrialDuration',settings.maxTrialDuration);
            
            % - below this are things that I don't really need persistent -
            app.envBaseNames = settings.vrOptions;
            app.vrLength = settings.vrLength;
            app.vrFrames = settings.vrFrames;
            app.vrFramesDS = settings.vrFramesDS;
            app.vrDSFactor = settings.vrDSFactor;
            app.vrRewPos = settings.vrRewPos; 
            app.vrRewTol = settings.vrRewTol;
            app.vrOrder = settings.vrOrder;
            app.vrActive = settings.vrActive;
            app.minimumITI.Value = settings.minimumITI;
            app.randomizeITI.Value = settings.randomITI;
            if settings.randomITI
                app.randomMeanITI.Value = settings.randomMeanITI;
                app.distributionITI.Value = settings.distributionITI;
            end

            % Trial Structure Loading
            app.condProbRew.Value = settings.condReward;
            if settings.condReward
                app.condTrialReward = settings.condTrialReward;
            else
                app.randReward.Value = settings.randomReward;
                if settings.randomReward
                    app.customProbReward.Value = settings.customRewardProb;
                    if settings.customRewardProb
                        app.customProbRewardGenerator.Value = settings.customRewardFunction;
                    else
                        app.randRewardArray = settings.randomRewardArray;
                        app.randRewardWindow.Value = settings.randomRewardWindow;
                        generateRewardProbList(app)
                    end
                end
            end

            app.condActiveLick.Value = settings.condActive;
            if settings.condActive
                app.condTrialActive = settings.condTrialActive;
            end
            app.lickEncoderAvailable.Value = settings.lickEncoderAvailable;
            app.activeLicking.Value = settings.activeLicking;
            
            app.condMvmtGain.Value = settings.condGain;
            if settings.condGain
                app.condTrialGain = settings.condTrialGain;
            else
                app.movementGain.Value = settings.movementGain;
                app.randGain.Value = settings.randomGain;
                if settings.randomGain
                    app.customGain.Value = settings.customGain;
                    if settings.customGain
                        app.customGainGenerator.Value = settings.customGainFunction;
                    else
                        app.minGain.Value = settings.minGain;
                        app.maxGain.Value = settings.maxGain;
                        app.randGainWindow.Value = settings.randGainWindow;
                    end
                end
            end

            % And get the frequencies if there are conditional trials
            if settings.condReward || settings.condActive || settings.condGain
                app.condTrialFreq = settings.condTrialFreq;
                needLength = true;
                if needLength && settings.condReward
                    numCondTrial = length(settings.condTrialReward);
                    needLength = false;
                end
                if needLength && settings.condActive
                    numCondTrial = length(settings.condTrialActive);
                    needLength = false;
                end
                if needLength && settings.condGain
                    numCondTrial = length(settings.condTrialGain);
                end
                if ~settings.condReward, app.condTrialReward = ones(1,numCondTrial); end
                if ~settings.condActive, app.condTrialActive = app.activeLicking.Value*ones(1,numCondTrial); end
                if ~settings.condGain, app.condTrialGain = ones(1,numCondTrial); end
                if length(app.condTrialFreq) ~= numCondTrial
                    error('Conditional trial frequency doesn''t have same length as other conditional trial vectors...'); 
                end
            end

            % Finally, update conditional structure (which takes care of
            % independent settings too)
            updateConditionalStructure(app)

            updateInitialBlockMenu(app)
            app.initActive.Value = settings.initBlock;
            app.initEnvironment.Value = settings.initEnvIdx;
            app.initNumTrials.Value = settings.initTrials;
            updateInitialBlockMenu(app)
            
            blockType = settings.blockType;
            blockNames = {'Even','Random','Custom'};
            app.evenBlocks.Value = blockType(1);
            app.randomBlocks.Value = blockType(2);
            app.customBlocks.Value = blockType(3);
            if blockType(1)
                app.evenTrialsPerBlock.Value = settings.blockTrialsPer;
            end
            if blockType(2)
                app.randomLengthMean.Value = settings.blockRandomMean;
                app.randomDistribution.Value = settings.blockRandomDistribution;
            end
            if blockType(3)
                app.customGenerator.Value = settings.blockCustomFunction;
            end

            % Now that everything is loaded, resume GUI operation
            changeBlockStructure(app, blockNames{blockType});
            loadEnvironmentOptions(app)
            randomizeITIValueChanged(app)
%             randGainValueChanged(app)
%             if app.randGain.Value
%                 customGainValueChanged(app)
%             end
%             randRewardValueChanged(app)
%             if app.randReward.Value
%                 customProbRewardValueChanged(app)
%             end
            presentMessage(app, 'Previous settings loaded!', 1);
        end

        % Value changed function: animalSettingsPath
        function animalSettingsPathValueChanged(app, event)
            validateAnimalSavePath(app, app.animalSettingsPath.Value)
        end

        % Button pushed function: animalSettingsButton
        function animalSettingsButtonPushed(app, event)
            validateAnimalSavePath(app, uigetdir())
        end

        % Value changed function: animalName
        function animalNameValueChanged(app, event)
            vrControlPersistent('animalName', app.animalName.Value);
        end

        % Callback function
        function printusrmessagesButtonPushed(app, event)
            disp(app.userMessages)
        end

        % Button pushed function: printrigparametersButton
        function printrigparametersButtonPushed(app, event)
            disp(app.rigInfo)
        end

        % Button pushed function: previewExperiment
        function previewExperimentButtonPushed(app, event)
            % Generate trial output structure
            trialStructure = generateTrialStructure(app);
            trialStructure.envName = string(cellfun(@(idx) ...
                trialStructure.getEnvName(idx), num2cell(trialStructure.envIndex), 'uni', 0));


            tbl = table(...
                (1:trialStructure.maxTrials)', ...
                trialStructure.envIndex, ...
                trialStructure.envName, ...
                trialStructure.roomLength, ...
                trialStructure.rewardPosition, ...
                trialStructure.rewardTolerance, ...
                trialStructure.probReward, ...
                trialStructure.activeLick, ...
                trialStructure.activeStop, ...
                trialStructure.mvmtGain, ...
                trialStructure.intertrialInterval, ...
                'variableNames',{'Trial #','EnvIdx','EnvName','Length','RewPos','RewTol','P(R)','Act.Lick','Act.Stop','MvmtGain','ITI'});
            disp(tbl)

            fprintf('Note: any randomness will be resampled when the actual experiment is run!! \n\n'); 

            % then print to screen here...
            
            % Immediate To-Do!!!
            % trials.vrIndex
            % trials.rewardLocation
            % trials.rewardAvailable
            % trials.activeLicking...
            % so on...
        end

        % Value changed function: condProbRew
        function condProbRew_Pushed(app, event)
            updateConditionalStructure(app);
        end

        % Value changed function: condMvmtGain
        function condMvmtGainValueChanged(app, event)
            updateConditionalStructure(app);
        end

        % Value changed function: condActiveLick
        function condActiveLickValueChanged(app, event)
            if app.condActiveLick.Value
                if ~app.lickEncoderAvailable.Value
                    presentMessage(app, 'Can''t activate conditional active licking without the lick encoder!!', 2);
                    highlightComponent(app, 'lickEncoderAvailable', 'FontColor');
                    app.condActiveLick.Value = false;
                    return
                end
                app.activeLicking.Value = true;
            end           
            updateConditionalStructure(app);
        end

        % Button pushed function: addnewButton
        function addnewButtonPushed(app, event)
            newProb = app.condProbRewSetting.Value; 
            newActive = app.condActiveLickSetting.Value; 
            newGain = app.condMvmtGainSetting.Value; 
            newFreq = app.condFreqSetting.Value;
            compareReward = newProb==app.condTrialReward; 
            compareActive = newActive==app.condTrialActive;
            compareGain = newGain==app.condTrialGain;
            if ~app.condProbRew.Value, compareReward(:)=true; end
            if ~app.condActiveLick.Value, compareActive(:)=true; end
            if ~app.condMvmtGain.Value, compareGain(:)=true; end
            idxAlready = find(compareReward & compareActive & compareGain); 
            if ~isempty(idxAlready)
                % Then the requested conditional trial type is already in list
                % Default behavior is to add the frequency
                updatedFreq = app.condTrialFreq(idxAlready) + newFreq;
                if updatedFreq<=0
                    presentMessage(app, 'Trial type frequencies must be positive...', 2);
                    return
                end
                app.condTrialFreq(idxAlready) = updatedFreq;
                presentMessage(app, 'New conditional trial type already in list-- added to it''s frequency', 1);
            elseif length(idxAlready) > 1
                error('Found multiple identical conditional trial types...');
            else
                if newFreq<=0
                    presentMessage(app, 'Trial type frequencies must be positive...', 2);
                    return
                end
                app.condTrialReward(end+1) = newProb;
                app.condTrialActive(end+1) = newActive;
                app.condTrialGain(end+1) = newGain;
                app.condTrialFreq(end+1) = newFreq;
                presentMessage(app, 'New conditional trial type added to list', 1);
            end
            generateCondTrialList(app)
        end

        % Button pushed function: removeButton
        function removeButtonPushed(app, event)
            cIndex = app.condTrialList.Value; 
            if isempty(cIndex)
                presentMessage(app, 'Can''t remove if none selected', 2);
                return
            end
            if length(app.condTrialReward) == 1
                presentMessage(app, 'Can''t remove last option', 2);
                return
            end
            app.condTrialReward(cIndex)=[];
            app.condTrialActive(cIndex)=[];
            app.condTrialGain(cIndex)=[];
            app.condTrialFreq(cIndex)=[];
            generateCondTrialList(app)
            presentMessage(app, 'Removed conditional trial type from list', 1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.topBox = uitextarea(app.UIFigure);
            app.topBox.Tooltip = {'Control a linear track experiment using pre-rendered movies.'};
            app.topBox.Value = {'vrControl'};
            app.animalNameLabel = uilabel(app.UIFigure);
            app.animalNameLabel.Text = 'Animal Name';
            app.animalName = uieditfield(app.UIFigure, 'text');
            app.animalName.ValueChangedFcn = createCallbackFcn(app, @animalNameValueChanged, true);
            app.animalName.Tooltip = {'determines variable names and save location throughout code'};
            app.lickEncoderAvailable = uicheckbox(app.UIFigure);
            app.lickEncoderAvailable.ValueChangedFcn = createCallbackFcn(app, @lickEncoderAvailableValueChanged, true);
            app.lickEncoderAvailable.Enable = 'off';
            app.lickEncoderAvailable.Tooltip = {'If checked, will use lick encoder'};
            app.lickEncoderAvailable.Text = 'Lick Encoder Available';
            app.lickEncoderAvailable.Value = true;
            app.updateDirectory = uibutton(app.UIFigure, 'push');
            app.updateDirectory.ButtonPushedFcn = createCallbackFcn(app, @updateDirectoryPushed, true);
            app.updateDirectory.Text = 'update path';
            app.activeLicking = uicheckbox(app.UIFigure);
            app.activeLicking.Enable = 'off';
            app.activeLicking.Tooltip = {'If checked, will use lick encoder'};
            app.activeLicking.Text = 'Active Licking';
            app.activeLicking.Value = true;
            app.activeStopping = uicheckbox(app.UIFigure);
            app.activeStopping.Tooltip = {'If checked, will use lick encoder'};
            app.activeStopping.Text = 'Active Stopping';
            app.sessionOffsetLabel = uilabel(app.UIFigure);
            app.sessionOffsetLabel.Text = 'Session Offset';
            app.sessionOffset = uieditfield(app.UIFigure, 'numeric');
            app.sessionOffset.Limits = [0 10000];
            app.sessionOffset.ValueChangedFcn = createCallbackFcn(app, @sessionOffsetValueChanged, true);
            app.sessionOffset.Tooltip = {'Will name each session: {AnimalName}_{SessionOffset+n}'};
            app.sessionOffset.Value = 700;
            app.maxTrialNumberLabel = uilabel(app.UIFigure);
            app.maxTrialNumberLabel.Text = 'Max Trial Number';
            app.maxTrialNumber = uieditfield(app.UIFigure, 'numeric');
            app.maxTrialNumber.Limits = [1 2000];
            app.maxTrialNumber.RoundFractionalValues = 'on';
            app.maxTrialNumber.ValueChangedFcn = createCallbackFcn(app, @maxTrialNumberValueChanged, true);
            app.maxTrialNumber.Tooltip = {'maximum number of trials (will automatically end session after this trial is reached)'};
            app.maxTrialNumber.Value = 500;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Tooltip = {'design block structure for virtual environments'};
            app.InitialTab = uitab(app.TabGroup);
            app.InitialTab.Title = 'Initial';
            app.initActive = uicheckbox(app.InitialTab);
            app.initActive.ValueChangedFcn = createCallbackFcn(app, @initActiveValueChanged, true);
            app.initActive.Tooltip = {'use an initial block of one environment, then switch to using all environments'};
            app.initActive.Text = 'Use Initial Block';
            app.initNumTrialsLabel = uilabel(app.InitialTab);
            app.initNumTrialsLabel.Text = 'Number of Trials';
            app.initNumTrials = uieditfield(app.InitialTab, 'numeric');
            app.initNumTrials.Limits = [0 500];
            app.initNumTrials.RoundFractionalValues = 'on';
            app.initNumTrials.ValueChangedFcn = createCallbackFcn(app, @initNumTrialsValueChanged, true);
            app.initNumTrials.Tooltip = {'set number of trials in initial block'};
            app.initNumTrials.Value = 10;
            app.initEnvironmentLabel = uilabel(app.InitialTab);
            app.initEnvironmentLabel.Text = 'Environment';
            app.initEnvironment = uidropdown(app.InitialTab);
            app.initEnvironment.Items = {'n/a'};
            app.initEnvironment.ItemsData = -1;
            app.initEnvironment.Tooltip = {'set environment to be used for initial block'};
            app.initEnvironment.Value = -1;
            app.EvenTab = uitab(app.TabGroup);
            app.EvenTab.Tooltip = {'one environment per block, same number of trials for all blocks'};
            app.EvenTab.Title = '*Even*';
            app.evenBlocks = uicheckbox(app.EvenTab);
            app.evenBlocks.ValueChangedFcn = createCallbackFcn(app, @evenBlocksValueChanged, true);
            app.evenBlocks.Enable = 'off';
            app.evenBlocks.Tooltip = {'use even block structure'};
            app.evenBlocks.Text = 'Use Even Block Structure';
            app.evenBlocks.Value = true;
            app.evenTrialsPerBlockLabel = uilabel(app.EvenTab);
            app.evenTrialsPerBlockLabel.Text = 'Trials Per Block';
            app.evenTrialsPerBlock = uieditfield(app.EvenTab, 'numeric');
            app.evenTrialsPerBlock.Limits = [1 Inf];
            app.evenTrialsPerBlock.Tooltip = {'set number of trials in each environment before moving to next environment'};
            app.evenTrialsPerBlock.Value = 5;
            app.RandomTab = uitab(app.TabGroup);
            app.RandomTab.Tooltip = {'one environment per block, randomly selected number of trials per block'};
            app.RandomTab.Title = 'Random';
            app.randomBlocks = uicheckbox(app.RandomTab);
            app.randomBlocks.ValueChangedFcn = createCallbackFcn(app, @randomBlocksValueChanged, true);
            app.randomBlocks.Tooltip = {'use random block structure'};
            app.randomBlocks.Text = 'Use Random Block Structure';
            app.randomDistributionLabel = uilabel(app.RandomTab);
            app.randomDistributionLabel.Enable = 'off';
            app.randomDistributionLabel.Text = 'Distribution';
            app.randomDistribution = uidropdown(app.RandomTab);
            app.randomDistribution.Items = {'Poisson'};
            app.randomDistribution.ItemsData = 1;
            app.randomDistribution.Enable = 'off';
            app.randomDistribution.Tooltip = {'distribution used for setting each block length'};
            app.randomDistribution.Value = 1;
            app.randomLengthMeanLabel = uilabel(app.RandomTab);
            app.randomLengthMeanLabel.Enable = 'off';
            app.randomLengthMeanLabel.Text = 'Block Length Mean';
            app.randomLengthMean = uieditfield(app.RandomTab, 'numeric');
            app.randomLengthMean.Limits = [1 Inf];
            app.randomLengthMean.RoundFractionalValues = 'on';
            app.randomLengthMean.Enable = 'off';
            app.randomLengthMean.Tooltip = {'mean for distribution selected above'};
            app.randomLengthMean.Value = 5;
            app.CustomTab = uitab(app.TabGroup);
            app.CustomTab.Tooltip = {'fully custom trial structure, uses a function that is on the Matlab path, name of function goes here and then it has to output the right things (tbd later)'};
            app.CustomTab.Title = 'Custom';
            app.customBlocks = uicheckbox(app.CustomTab);
            app.customBlocks.ValueChangedFcn = createCallbackFcn(app, @customBlocksValueChanged, true);
            app.customBlocks.Tooltip = {'use custom block structure (currently blocked because code not written) '};
            app.customBlocks.Text = 'Use Custom Block Structure';
            app.customGeneratorLabel = uilabel(app.CustomTab);
            app.customGeneratorLabel.Enable = 'off';
            app.customGeneratorLabel.Text = 'Block Structure Generator Function';
            app.customGenerator = uieditfield(app.CustomTab, 'text');
            app.customGenerator.ValueChangedFcn = createCallbackFcn(app, @customGeneratorValueChanged, true);
            app.customGenerator.Enable = 'off';
            app.customGenerator.Tooltip = {'function used to generate custom block structure -- note that this is finicky and due to it''s nature is hard to error check. If using a custom generator function, it''s a good idea to check routinely whether it works using the preview button'};
            app.customPreview = uibutton(app.CustomTab, 'push');
            app.customPreview.ButtonPushedFcn = createCallbackFcn(app, @customPreviewButtonPushed, true);
            app.customPreview.Tooltip = {'using current app.environmentInfo settings, preview what the custom block generator would produce'};
            app.customPreview.Text = 'preview';
            app.EnvironmentBlockStructureLabel = uilabel(app.UIFigure);
            app.EnvironmentBlockStructureLabel.Text = 'Environment Block Structure';
            app.fileextensionforenvironmentsLabel = uilabel(app.UIFigure);
            app.fileextensionforenvironmentsLabel.Enable = 'off';
            app.fileextensionforenvironmentsLabel.Text = 'file extension for environments';
            app.vrExtension = uieditfield(app.UIFigure, 'text');
            app.vrExtension.ValueChangedFcn = createCallbackFcn(app, @vrExtensionValueChanged, true);
            app.vrExtension.Enable = 'off';
            app.vrExtension.Tooltip = {'file extension to use to look in virtual environment path (can include or omit leading period)'};
            app.vrExtension.Value = '.tif';
            app.messagestouserTextAreaLabel = uilabel(app.UIFigure);
            app.messagestouserTextAreaLabel.Text = 'messages to user';
            app.messagestouserTextArea = uitextarea(app.UIFigure);
            app.messagestouserTextArea.Editable = 'off';
            app.messagestouserTextArea.Tooltip = {'This window displays messages to user. It updates to red if there is a passable error. All updates are stored and available upon output of vrControl GUI.  '};
            app.envActive = uibutton(app.UIFigure, 'state');
            app.envActive.ValueChangedFcn = createCallbackFcn(app, @envActiveValueChanged, true);
            app.envActive.Enable = 'off';
            app.envActive.Tooltip = {'toggle to activate environment (redundant with item selection in listbox)'};
            app.envActive.Text = 'env active';
            app.envOrderLabel = uilabel(app.UIFigure);
            app.envOrderLabel.Enable = 'off';
            app.envOrderLabel.Text = 'Order in block structure';
            app.envOrder = uidropdown(app.UIFigure);
            app.envOrder.Items = {'n/a', '1', '2'};
            app.envOrder.ItemsData = [-1 1 2];
            app.envOrder.ValueChangedFcn = createCallbackFcn(app, @envOrderValueChanged, true);
            app.envOrder.Enable = 'off';
            app.envOrder.Tooltip = {'determines order of environment in block structure (if random env selection is on, then this is irrelevant)'};
            app.envOrder.Value = -1;
            app.rewardToleranceLabel = uilabel(app.UIFigure);
            app.rewardToleranceLabel.Enable = 'off';
            app.rewardToleranceLabel.Text = 'Reward Tolerance';
            app.rewardTolerance = uieditfield(app.UIFigure, 'numeric');
            app.rewardTolerance.Limits = [0 2000];
            app.rewardTolerance.ValueChangedFcn = createCallbackFcn(app, @rewardToleranceValueChanged, true);
            app.rewardTolerance.Enable = 'off';
            app.rewardTolerance.Tooltip = {'length of reward zone in units of percentage or cm'};
            app.rewardTolerance.Value = 10;
            app.rewardPositionLabel = uilabel(app.UIFigure);
            app.rewardPositionLabel.Enable = 'off';
            app.rewardPositionLabel.Text = 'Reward Position';
            app.rewardPosition = uieditfield(app.UIFigure, 'numeric');
            app.rewardPosition.Limits = [0 2000];
            app.rewardPosition.ValueChangedFcn = createCallbackFcn(app, @rewardPositionValueChanged, true);
            app.rewardPosition.Enable = 'off';
            app.rewardPosition.Tooltip = {'length of reward zone in units of percentage or cm'};
            app.rewardPosition.Value = 200;
            app.dsFactorLabel = uilabel(app.UIFigure);
            app.dsFactorLabel.Enable = 'off';
            app.dsFactorLabel.Text = 'Downsample Factor';
            app.dsFactor = uieditfield(app.UIFigure, 'numeric');
            app.dsFactor.Limits = [1 1000];
            app.dsFactor.RoundFractionalValues = 'on';
            app.dsFactor.ValueChangedFcn = createCallbackFcn(app, @dsFactorValueChanged, true);
            app.dsFactor.Enable = 'off';
            app.dsFactor.Tooltip = {'factor to downsample environment arrays (for quick loading and testing)'};
            app.dsFactor.Value = 120;
            app.envLengthLabel = uilabel(app.UIFigure);
            app.envLengthLabel.Enable = 'off';
            app.envLengthLabel.Text = 'Env Length (cm)';
            app.envLength = uieditfield(app.UIFigure, 'numeric');
            app.envLength.Limits = [1 2000];
            app.envLength.RoundFractionalValues = 'on';
            app.envLength.ValueChangedFcn = createCallbackFcn(app, @envLengthValueChanged, true);
            app.envLength.Enable = 'off';
            app.envLength.Tooltip = {'length of corridor (in centimeters)'};
            app.envLength.Value = 250;
            app.envSettingsLabel = uilabel(app.UIFigure);
            app.envSettingsLabel.Enable = 'off';
            app.envSettingsLabel.Text = 'Update Environnment Settings';
            app.envSettings = uidropdown(app.UIFigure);
            app.envSettings.Items = {'n/a'};
            app.envSettings.ValueChangedFcn = createCallbackFcn(app, @envSettingsValueChanged, true);
            app.envSettings.Enable = 'off';
            app.envSettings.Tooltip = {'select environment to update its settings'};
            app.envSettings.Value = 'n/a';
            app.vrenvInUseLabel = uilabel(app.UIFigure);
            app.vrenvInUseLabel.Text = 'Virtual Environments In Use - Double Click to Deactivate';
            app.vrenvInUse = uilistbox(app.UIFigure);
            app.vrenvInUse.Items = {'path not defined'};
            app.vrenvInUse.Multiselect = 'on';
            app.vrenvInUse.ValueChangedFcn = createCallbackFcn(app, @vrenvInUseValueChanged, true);
            app.vrenvInUse.Enable = 'off';
            app.vrenvInUse.Tooltip = {'select virtual environments to use in session, these are from the directory selected above in "virtual environment path"'};
            app.vrenvInUse.Value = {};
            app.vrenvOptionsLabel = uilabel(app.UIFigure);
            app.vrenvOptionsLabel.Text = 'VR Options - Double Click to Activate';
            app.vrenvOptions = uilistbox(app.UIFigure);
            app.vrenvOptions.Items = {'path not defined'};
            app.vrenvOptions.Multiselect = 'on';
            app.vrenvOptions.ValueChangedFcn = createCallbackFcn(app, @vrenvOptionsValueChanged, true);
            app.vrenvOptions.Enable = 'off';
            app.vrenvOptions.Tooltip = {'select virtual environments to use in session, these are from the directory selected above in "virtual environment path"'};
            app.vrenvOptions.Value = {};
            app.vrDirectoryLabel = uilabel(app.UIFigure);
            app.vrDirectoryLabel.Text = 'virtual environment path';
            app.vrDirectory = uieditfield(app.UIFigure, 'text');
            app.vrDirectory.ValueChangedFcn = createCallbackFcn(app, @vrDirectoryValueChanged, true);
            app.printenvironmentinfoButton = uibutton(app.UIFigure, 'push');
            app.printenvironmentinfoButton.ButtonPushedFcn = createCallbackFcn(app, @printenvironmentinfoButtonPushed, true);
            app.printenvironmentinfoButton.Text = 'print environment info';
            app.maxTrialDurationLabel = uilabel(app.UIFigure);
            app.maxTrialDurationLabel.Text = 'Max Trial Duration';
            app.maxTrialDuration = uieditfield(app.UIFigure, 'numeric');
            app.maxTrialDuration.Limits = [1 1200];
            app.maxTrialDuration.RoundFractionalValues = 'on';
            app.maxTrialDuration.Tooltip = {'maximum number of trials (will automatically end session after this trial is reached)'};
            app.maxTrialDuration.Value = 60;
            app.IntertrialTimingsPanel = uipanel(app.UIFigure);
            app.IntertrialTimingsPanel.Title = 'Intertrial Timings';
            app.minimumITILabel = uilabel(app.IntertrialTimingsPanel);
            app.minimumITILabel.Text = 'ITI (sec)';
            app.minimumITI = uieditfield(app.IntertrialTimingsPanel, 'numeric');
            app.minimumITI.Limits = [0 Inf];
            app.minimumITI.Tooltip = {'minimum intertrial interval. If randomize selected, then the random ITI duration is added to the minimum.'};
            app.minimumITI.Value = 1;
            app.randomizeITI = uicheckbox(app.IntertrialTimingsPanel);
            app.randomizeITI.ValueChangedFcn = createCallbackFcn(app, @randomizeITIValueChanged, true);
            app.randomizeITI.Tooltip = {'randomize ITI times'};
            app.randomizeITI.Text = 'Randomize ITI';
            app.randomizeITI.Value = true;
            app.randomMeanITILabel = uilabel(app.IntertrialTimingsPanel);
            app.randomMeanITILabel.Text = 'Mean ITI';
            app.randomMeanITI = uieditfield(app.IntertrialTimingsPanel, 'numeric');
            app.randomMeanITI.Limits = [0 Inf];
            app.randomMeanITI.Tooltip = {'mean of random ITI distribution'};
            app.randomMeanITI.Value = 0.5;
            app.distributionITI = uidropdown(app.IntertrialTimingsPanel);
            app.distributionITI.Items = {'Uniform', 'Exponential'};
            app.distributionITI.ItemsData = [1 2];
            app.distributionITI.Tooltip = {'distribution used for generating ITIs'};
            app.distributionITI.Value = 2;
            app.printrigparametersButton = uibutton(app.UIFigure, 'push');
            app.printrigparametersButton.ButtonPushedFcn = createCallbackFcn(app, @printrigparametersButtonPushed, true);
            app.printrigparametersButton.Tooltip = {'upon startup, this GUI loads rig parameters. This button prints them to screen. Changing them requires hard coding the change in the relevant code'};
            app.printrigparametersButton.Text = 'print rig parameters';
            app.loadSettings = uibutton(app.UIFigure, 'push');
            app.loadSettings.ButtonPushedFcn = createCallbackFcn(app, @loadSettingsButtonPushed, true);
            app.loadSettings.Tooltip = {'load settings from: fullfile(animalSettingsPath, sprintf(''vrControlSettings_%s.mat'',animalName))'};
            app.loadSettings.Text = 'load settings';
            app.saveSettings = uibutton(app.UIFigure, 'push');
            app.saveSettings.ButtonPushedFcn = createCallbackFcn(app, @saveSettingsPushed, true);
            app.saveSettings.Tooltip = {'save settings to: fullfile(animalSettingsPath, sprintf(''vrControlSettings_%s.mat'',animalName))'};
            app.saveSettings.Text = 'save settings';
            app.previewExperiment = uibutton(app.UIFigure, 'push');
            app.previewExperiment.ButtonPushedFcn = createCallbackFcn(app, @previewExperimentButtonPushed, true);
            app.previewExperiment.Tooltip = {'print experiment structure generated from current settings to workspace -- NOTE! if there''s any random components, it will be resampled'};
            app.previewExperiment.Text = 'preview experiment';
            app.runExperiment = uibutton(app.UIFigure, 'push');
            app.runExperiment.Text = 'run experiment';
            app.animalSettingsPath = uieditfield(app.UIFigure, 'text');
            app.animalSettingsPath.ValueChangedFcn = createCallbackFcn(app, @animalSettingsPathValueChanged, true);
            app.animalSettingsPath.Tooltip = {'path where settings of this GUI are stored. Note that this is also a rig parameter so is hardcoded and preloaded upon startup'};
            app.animalSettingsButton = uibutton(app.UIFigure, 'push');
            app.animalSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @animalSettingsButtonPushed, true);
            app.animalSettingsButton.Tooltip = {'click to update settings path'};
            app.animalSettingsButton.Text = 'update settings path';
            app.animalSettingsIDLabel = uilabel(app.UIFigure);
            app.animalSettingsIDLabel.Text = 'Animal Settings ID';
            app.animalSettingsID = uieditfield(app.UIFigure, 'numeric');
            app.animalSettingsID.Limits = [1 100];
            app.animalSettingsID.RoundFractionalValues = 'on';
            app.animalSettingsID.Value = 1;
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.ConditionalTrialTypesTab = uitab(app.TabGroup2);
            app.ConditionalTrialTypesTab.Title = '*Conditional Trial Types*';
            app.condTrialList = uilistbox(app.ConditionalTrialTypesTab);
            app.condTrialList.Items = {'P(R)=1, Active=1, Gain=1, 100%, Freq=1'};
            app.condTrialList.Value = 'P(R)=1, Active=1, Gain=1, 100%, Freq=1';
            app.rewProbNewProbLabel_2 = uilabel(app.ConditionalTrialTypesTab);
            app.rewProbNewProbLabel_2.Text = 'P(reward)';
            app.condProbRewSetting = uieditfield(app.ConditionalTrialTypesTab, 'numeric');
            app.condProbRewSetting.Limits = [0 1];
            app.condProbRewSetting.Value = 1;
            app.rewProbNewFreqLabel_2 = uilabel(app.ConditionalTrialTypesTab);
            app.rewProbNewFreqLabel_2.Text = 'Frequency';
            app.condFreqSetting = uieditfield(app.ConditionalTrialTypesTab, 'numeric');
            app.condFreqSetting.RoundFractionalValues = 'on';
            app.condFreqSetting.Value = 1;
            app.rewProbNewFreqLabel_4 = uilabel(app.ConditionalTrialTypesTab);
            app.rewProbNewFreqLabel_4.Text = 'Active Lick';
            app.condActiveLickSetting = uieditfield(app.ConditionalTrialTypesTab, 'numeric');
            app.condActiveLickSetting.Limits = [0 Inf];
            app.condActiveLickSetting.Value = 1;
            app.addnewButton = uibutton(app.ConditionalTrialTypesTab, 'push');
            app.addnewButton.ButtonPushedFcn = createCallbackFcn(app, @addnewButtonPushed, true);
            app.addnewButton.Text = 'add new';
            app.removeButton = uibutton(app.ConditionalTrialTypesTab, 'push');
            app.removeButton.ButtonPushedFcn = createCallbackFcn(app, @removeButtonPushed, true);
            app.removeButton.Text = 'remove';
            app.condActiveLick = uibutton(app.ConditionalTrialTypesTab, 'state');
            app.condActiveLick.ValueChangedFcn = createCallbackFcn(app, @condActiveLickValueChanged, true);
            app.condActiveLick.Text = 'active lick cond.';
            app.condActiveLick.Value = true;
            app.condProbRew = uibutton(app.ConditionalTrialTypesTab, 'state');
            app.condProbRew.ValueChangedFcn = createCallbackFcn(app, @condProbRew_Pushed, true);
            app.condProbRew.Text = 'p(reward) cond.';
            app.condProbRew.Value = true;
            app.condMvmtGain = uibutton(app.ConditionalTrialTypesTab, 'state');
            app.condMvmtGain.ValueChangedFcn = createCallbackFcn(app, @condMvmtGainValueChanged, true);
            app.condMvmtGain.Text = 'mvmt gain cond.';
            app.rewProbNewFreqLabel_3 = uilabel(app.ConditionalTrialTypesTab);
            app.rewProbNewFreqLabel_3.Text = 'Mvmt Gain';
            app.condMvmtGainSetting = uieditfield(app.ConditionalTrialTypesTab, 'numeric');
            app.condMvmtGainSetting.Limits = [0 Inf];
            app.condMvmtGainSetting.Value = 1;
            app.IndependentPRewardMovementGainTab = uitab(app.TabGroup2);
            app.IndependentPRewardMovementGainTab.Title = 'Independent P(Reward) & Movement Gain';
            app.randGain = uicheckbox(app.IndependentPRewardMovementGainTab);
            app.randGain.ValueChangedFcn = createCallbackFcn(app, @randGainValueChanged, true);
            app.randGain.Enable = 'off';
            app.randGain.Tooltip = {'randomize gain between movement and VR corridor'};
            app.randGain.Text = 'Randomize';
            app.randGainWindow = uidropdown(app.IndependentPRewardMovementGainTab);
            app.randGainWindow.Items = {'Per Trial'};
            app.randGainWindow.ItemsData = 1;
            app.randGainWindow.Enable = 'off';
            app.randGainWindow.Tooltip = {'can either randomize each trial or each block'};
            app.randGainWindow.Value = 1;
            app.customGain = uicheckbox(app.IndependentPRewardMovementGainTab);
            app.customGain.ValueChangedFcn = createCallbackFcn(app, @customGainValueChanged, true);
            app.customGain.Enable = 'off';
            app.customGain.Tooltip = {'if selected, use custom gain function rather than uniform random distribution. Enables if a valid custom gain function is provided.'};
            app.customGain.Text = '';
            app.customGainGenerator = uieditfield(app.IndependentPRewardMovementGainTab, 'text');
            app.customGainGenerator.ValueChangedFcn = createCallbackFcn(app, @customGainGeneratorValueChanged, true);
            app.customGainGenerator.Enable = 'off';
            app.customGainGenerator.Tooltip = {'generator function for accepting trial structure and adding a custom gain value'};
            app.minGainLabel = uilabel(app.IndependentPRewardMovementGainTab);
            app.minGainLabel.Enable = 'off';
            app.minGainLabel.Text = 'min';
            app.minGain = uieditfield(app.IndependentPRewardMovementGainTab, 'numeric');
            app.minGain.Limits = [0 1];
            app.minGain.Enable = 'off';
            app.minGain.Tooltip = {'minimum possible gain'};
            app.minGain.Value = 0.5;
            app.maxGainLabel = uilabel(app.IndependentPRewardMovementGainTab);
            app.maxGainLabel.Enable = 'off';
            app.maxGainLabel.Text = 'max';
            app.maxGain = uieditfield(app.IndependentPRewardMovementGainTab, 'numeric');
            app.maxGain.Limits = [1 10];
            app.maxGain.Enable = 'off';
            app.maxGain.Tooltip = {'maximum possible gain'};
            app.maxGain.Value = 2;
            app.movementGainLabel = uilabel(app.IndependentPRewardMovementGainTab);
            app.movementGainLabel.Enable = 'off';
            app.movementGainLabel.Tooltip = {'sets the baseline gain -- advisable to set this =1 because it''s redundant with "env length"'};
            app.movementGainLabel.Text = 'Baseline Gain Value';
            app.movementGain = uieditfield(app.IndependentPRewardMovementGainTab, 'numeric');
            app.movementGain.Limits = [0.01 10];
            app.movementGain.Enable = 'off';
            app.movementGain.Tooltip = {'sets the baseline gain -- advisable to set this =1 because it''s redundant with "env length"'};
            app.movementGain.Value = 1;
            app.randReward = uicheckbox(app.IndependentPRewardMovementGainTab);
            app.randReward.ValueChangedFcn = createCallbackFcn(app, @randRewardValueChanged, true);
            app.randReward.Enable = 'off';
            app.randReward.Tooltip = {'randomize reward probability on each trial'};
            app.randReward.Text = 'Random reward probability';
            app.randRewardWindow = uidropdown(app.IndependentPRewardMovementGainTab);
            app.randRewardWindow.Items = {'Per Trial'};
            app.randRewardWindow.ItemsData = 1;
            app.randRewardWindow.Enable = 'off';
            app.randRewardWindow.Tooltip = {'can either randomize on each trial or each block'};
            app.randRewardWindow.Value = 1;
            app.customProbRewardGenerator = uieditfield(app.IndependentPRewardMovementGainTab, 'text');
            app.customProbRewardGenerator.ValueChangedFcn = createCallbackFcn(app, @customProbRewardGeneratorValueChanged, true);
            app.customProbRewardGenerator.Enable = 'off';
            app.customProbRewardGenerator.Tooltip = {'generator function for accepting trial structure and adding a custom reward probability value'};
            app.customProbReward = uicheckbox(app.IndependentPRewardMovementGainTab);
            app.customProbReward.ValueChangedFcn = createCallbackFcn(app, @customProbRewardValueChanged, true);
            app.customProbReward.Enable = 'off';
            app.customProbReward.Tooltip = {'if selected, use custom reward probability function rather than P(reward) array. Enables if a valid custom p(rew) function is provided.'};
            app.customProbReward.Text = '';
            app.rewProbList = uilistbox(app.IndependentPRewardMovementGainTab);
            app.rewProbList.Items = {};
            app.rewProbList.Enable = 'off';
            app.rewProbList.Value = {};
            app.rewProbAddNew = uibutton(app.IndependentPRewardMovementGainTab, 'push');
            app.rewProbAddNew.ButtonPushedFcn = createCallbackFcn(app, @rewProbAddNewButtonPushed, true);
            app.rewProbAddNew.Enable = 'off';
            app.rewProbAddNew.Text = 'add';
            app.rewProbRemove = uibutton(app.IndependentPRewardMovementGainTab, 'push');
            app.rewProbRemove.ButtonPushedFcn = createCallbackFcn(app, @rewProbRemoveButtonPushed, true);
            app.rewProbRemove.Enable = 'off';
            app.rewProbRemove.Text = 'rmv';
            app.rewProbNewProbLabel = uilabel(app.IndependentPRewardMovementGainTab);
            app.rewProbNewProbLabel.Enable = 'off';
            app.rewProbNewProbLabel.Text = 'P(reward)';
            app.rewProbNewProb = uieditfield(app.IndependentPRewardMovementGainTab, 'numeric');
            app.rewProbNewProb.Limits = [0 1];
            app.rewProbNewProb.Enable = 'off';
            app.rewProbNewProb.Value = 0.5;
            app.rewProbNewFreqLabel = uilabel(app.IndependentPRewardMovementGainTab);
            app.rewProbNewFreqLabel.Enable = 'off';
            app.rewProbNewFreqLabel.Text = 'Frequency';
            app.rewProbNewFreq = uieditfield(app.IndependentPRewardMovementGainTab, 'numeric');
            app.rewProbNewFreq.Limits = [0 Inf];
            app.rewProbNewFreq.Enable = 'off';
            app.rewProbNewFreq.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vrControlGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end