classdef vrControlHabituationModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        rewDeliveredUL                  matlab.ui.control.UIAxes
        rewDeliveredSeconds             matlab.ui.control.UIAxes
        MouseSpeedcmsGaugeLabel         matlab.ui.control.Label
        speed                           matlab.ui.control.LinearGauge
        position                        matlab.ui.control.LinearGauge
        MousePositioncmGaugeLabel       matlab.ui.control.Label
        MaxPositionLimitEditFieldLabel  matlab.ui.control.Label
        positionLimit                   matlab.ui.control.NumericEditField
        LickIndicatorLabel              matlab.ui.control.Label
        lickIndicator                   matlab.ui.control.Lamp
        elapsedMovement                 matlab.ui.control.LinearGauge
        MovementSinceLastRewardDeliverycmLabel  matlab.ui.control.Label
        deliverReward                   matlab.ui.control.Button
        RewardSizeEditFieldLabel        matlab.ui.control.Label
        rewardSize                      matlab.ui.control.NumericEditField
        RewardUnitsDropDownLabel        matlab.ui.control.Label
        rewardUnits                     matlab.ui.control.DropDown
        DoUpdatesButton                 matlab.ui.control.Button
        UpdateFrequencyhzEditFieldLabel  matlab.ui.control.Label
        updateFrequency                 matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        rigInfo
        lickActive % Inherited from rigInfo
        currentPosition = 0; % Mouses current position on wheel (in cm)
        lastRewardPosition = 0; % Position of last reward delivery (in cm)
        elapsedLimit = 200;
        standardLimit = 200; 
        rewTime = 0.2
        updatesActive = false;
        updatesTimer
    end
    
    properties (Access = public)
        session
        rotEnc
        sessionVal
        rewVal
    end
    
    methods (Access = public)
        
        function updatePosition(app, newPosition)
            app.currentPosition = newPosition;
            app.position.Value = mod(newPosition, app.position.Limits(2)); 
            updateElapsedMovement(app)
        end

        function updateSpeed(app, newSpeed)
            app.speed.Value = newSpeed;
        end

        function updateLickIndicator(app, state)
            if app.lickActive
                if state
                    app.lickIndicator.Color = 'g';
                else
                    app.lickIndicator.Color = 'r';
                end
            else
                app.lickIndicator.Color = 'k';
            end
        end

    end

    methods (Access = private)

        function updateElapsedMovement(app)
            movementSinceLastReward = app.currentPosition - app.lastRewardPosition; 
            if abs(movementSinceLastReward) > app.elapsedLimit
                app.elapsedLimit = max([app.elapsedLimit*2, app.standardLimit]);
                changeLimit = true; 
            elseif abs(movementSinceLastReward) < app.elapsedLimit/2
                app.elapsedLimit = max([app.elapsedLimit/2, app.standardLimit]);
                changeLimit = true;
            elseif movementSinceLastReward==0
                app.elapsedLimit = app.standardLimit;
                changeLimit = true; 
            else
                changeLimit = false;
            end
            if changeLimit
                app.elapsedMovement.Limits = app.elapsedLimit * [-1, 1];
                app.elapsedMovement.MajorTicks = linspace(-app.elapsedLimit,app.elapsedLimit,11);
                app.elapsedMovement.MinorTicks = linspace(-app.elapsedLimit,app.elapsedLimit,101);
                drawnow()
            end
            app.elapsedMovement.Value = movementSinceLastReward; 
        end

        
        function monitorBehavior(app)
            app.currentPosition = 0;
            app.lastRewardPosition = 0;
            app.updatesTimer = tic;
            lastTimeStamp = toc(app.updatesTimer);
            while app.updatesActive
                cmovement = getMovement(app);
                updatePosition(app, app.currentPosition + cmovement);
                ctime = toc(app.updatesTimer);
                updateSpeed(app, cmovement / (ctime - lastTimeStamp));
                lastTimeStamp = ctime;
                updateElapsedMovement(app);
                pause(1/app.updateFrequency.Value);
            end
        end
        
        function movement = getMovement(app)
            wheelPos = app.rotEnc.readPositionAndZero;
            movement =  app.rigInfo.rotEncSign * wheelPos / app.rigInfo.wheelToVR * app.rigInfo.wheelCircumference;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.rigInfo = vrControlRigParameters();
            app.rigInfo.wheelCircumference = 2*pi*app.rigInfo.wheelRadius;
            app.UIFigure.Position([1 2]) = app.rigInfo.localFigurePosition; 
            app.lickActive = app.rigInfo.lickEncoderAvailable;
            
            app.session = daq.createSession('ni');
            app.session.Rate = app.rigInfo.NIsessRate;
            app.rotEnc = DaqRotaryEncoder;
            app.rotEnc.DaqSession = app.session;
            app.rotEnc.DaqId = app.rigInfo.NIdevID;
            app.rotEnc.DaqChannelId = app.rigInfo.NIRotEnc;
            app.rotEnc.createDaqChannel;
            app.rotEnc.zero();
            
            app.sessionVal = daq.createSession('ni');
            app.sessionVal.Rate = app.rigInfo.NIsessRate;
            app.rewVal = DaqRewardValve;
            load(app.rigInfo.WaterCalibrationFile);
            app.rewVal.DaqSession = app.sessionVal;
            app.rewVal.DaqId = app.rigInfo.NIdevID;
            app.rewVal.DaqChannelId = app.rigInfo.NIRewVal;
            app.rewVal.createDaqChannel;
            app.rewVal.MeasuredDeliveries = Water_calibs(end).measuredDeliveries;
            app.rewVal.OpenValue = 10;
            app.rewVal.ClosedValue = 0;
            app.rewVal.close;
            app.rewTime = 0.200; % s 
            
            app.rewVal.prepareRewardDelivery(app.rewTime, 's');
            
            % This helps wheel initialize
            app.currentPosition = getMovement(app);
            updateElapsedMovement(app)
        end

        % Value changed function: positionLimit
        function positionLimitValueChanged(app, event)
            app.position.Limits = [0 app.positionLimit.Value];
            app.position.MajorTicks = linspace(0, app.positionLimit.Value, 11);
            app.position.MinorTicks = linspace(0, app.positionLimit.Value, 101);
            drawnow()
        end

        % Button pushed function: deliverReward
        function deliverRewardButtonPushed(app, event)
            app.rewVal.activateDigitalDelivery()
            app.lastRewardPosition = app.currentPosition;
            updateElapsedMovement(app);
        end

        % Button pushed function: DoUpdatesButton
        function DoUpdatesButtonPushed(app, event)
            app.updatesActive = ~app.updatesActive;
            if app.updatesActive
                app.DoUpdatesButton.Text = 'Stop Updates';
                monitorBehavior(app)
            else
                app.DoUpdatesButton.Text = 'Do Updates';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.updatesActive
                DoUpdatesButtonPushed(app)
                return
            end
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.rewDeliveredUL = uiaxes(app.UIFigure);
            title(app.rewDeliveredUL, '')
            xlabel(app.rewDeliveredUL, '')
            ylabel(app.rewDeliveredUL, 'Reward Delivered (ul)')
            zlabel(app.rewDeliveredUL, 'Z')
            app.rewDeliveredUL.XLim = [0 2];
            app.rewDeliveredUL.YLim = [0 10];
            app.rewDeliveredUL.XTick = [];
            app.rewDeliveredUL.XTickLabel = '';
            app.rewDeliveredUL.YTick = [0 5 10];
            app.rewDeliveredSeconds = uiaxes(app.UIFigure);
            title(app.rewDeliveredSeconds, '')
            xlabel(app.rewDeliveredSeconds, '')
            ylabel(app.rewDeliveredSeconds, 'Reward Delivered (s)')
            zlabel(app.rewDeliveredSeconds, 'Z')
            app.rewDeliveredSeconds.XLim = [0 2];
            app.rewDeliveredSeconds.YLim = [0 10];
            app.rewDeliveredSeconds.XTick = [];
            app.rewDeliveredSeconds.XTickLabel = '';
            app.rewDeliveredSeconds.YTick = [0 5 10];
            app.MouseSpeedcmsGaugeLabel = uilabel(app.UIFigure);
            app.MouseSpeedcmsGaugeLabel.Text = 'Mouse Speed (cm/s)';
            app.speed = uigauge(app.UIFigure, 'linear');
            app.speed.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.position = uigauge(app.UIFigure, 'linear');
            app.position.Limits = [0 400];
            app.position.MajorTicks = [0 40 80 120 160 200 240 280 320 360 400];
            app.position.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100 104 108 112 116 120 124 128 132 136 140 144 148 152 156 160 164 168 172 176 180 184 188 192 196 200 204 208 212 216 220 224 228 232 236 240 244 248 252 256 260 264 268 272 276 280 284 288 292 296 300 304 308 312 316 320 324 328 332 336 340 344 348 352 356 360 364 368 372 376 380 384 388 392 396 400];
            app.MousePositioncmGaugeLabel = uilabel(app.UIFigure);
            app.MousePositioncmGaugeLabel.Text = 'Mouse Position (cm)';
            app.MaxPositionLimitEditFieldLabel = uilabel(app.UIFigure);
            app.MaxPositionLimitEditFieldLabel.Text = 'Max Position Limit';
            app.positionLimit = uieditfield(app.UIFigure, 'numeric');
            app.positionLimit.Limits = [1 Inf];
            app.positionLimit.ValueChangedFcn = createCallbackFcn(app, @positionLimitValueChanged, true);
            app.positionLimit.Value = 400;
            app.LickIndicatorLabel = uilabel(app.UIFigure);
            app.LickIndicatorLabel.Text = 'Lick Indicator';
            app.lickIndicator = uilamp(app.UIFigure);
            app.elapsedMovement = uigauge(app.UIFigure, 'linear');
            app.elapsedMovement.Limits = [-50 50];
            app.elapsedMovement.MajorTicks = [-50 -40 -30 -20 -10 0 10 20 30 40 50];
            app.MovementSinceLastRewardDeliverycmLabel = uilabel(app.UIFigure);
            app.MovementSinceLastRewardDeliverycmLabel.Text = 'Movement Since Last Reward Delivery (cm)';
            app.deliverReward = uibutton(app.UIFigure, 'push');
            app.deliverReward.ButtonPushedFcn = createCallbackFcn(app, @deliverRewardButtonPushed, true);
            app.deliverReward.Text = 'Deliver Reward';
            app.RewardSizeEditFieldLabel = uilabel(app.UIFigure);
            app.RewardSizeEditFieldLabel.Enable = 'off';
            app.RewardSizeEditFieldLabel.Text = 'Reward Size';
            app.rewardSize = uieditfield(app.UIFigure, 'numeric');
            app.rewardSize.Limits = [0 2];
            app.rewardSize.Enable = 'off';
            app.rewardSize.Value = 0.2;
            app.RewardUnitsDropDownLabel = uilabel(app.UIFigure);
            app.RewardUnitsDropDownLabel.Enable = 'off';
            app.RewardUnitsDropDownLabel.Text = 'Reward Units';
            app.rewardUnits = uidropdown(app.UIFigure);
            app.rewardUnits.Items = {'s', 'ul'};
            app.rewardUnits.Enable = 'off';
            app.rewardUnits.Value = 's';
            app.DoUpdatesButton = uibutton(app.UIFigure, 'push');
            app.DoUpdatesButton.ButtonPushedFcn = createCallbackFcn(app, @DoUpdatesButtonPushed, true);
            app.DoUpdatesButton.Text = 'Do Updates';
            app.UpdateFrequencyhzEditFieldLabel = uilabel(app.UIFigure);
            app.UpdateFrequencyhzEditFieldLabel.Text = 'Update Frequency (hz)';
            app.updateFrequency = uieditfield(app.UIFigure, 'numeric');
            app.updateFrequency.Limits = [1 100];
            app.updateFrequency.Value = 33;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vrControlHabituationModule

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end